classdef ExpFitGUI < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    ExpFitGUIUIFigure         matlab.ui.Figure
    ddShow                    matlab.ui.control.DropDown
    tLocations                matlab.ui.control.Table
    CalibrationLabel          matlab.ui.control.Label
    tCalibrations             matlab.ui.control.Table
    eFirstPoint               matlab.ui.control.NumericEditField
    FirstPointEditFieldLabel  matlab.ui.control.Label
    eFileName                 matlab.ui.control.EditField
    TextfileEditFieldLabel    matlab.ui.control.Label
    RunButton                 matlab.ui.control.Button
    BrowseButton              matlab.ui.control.Button
  end

  
  properties (Access = private)
    LastFile % Description
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Button pushed function: BrowseButton
    function BrowseButtonPushed(app, event)
      [filename, pathname, ~] = uigetfile({'*.txt','TEXT-files (*.txt)'; ...
        '*.*',  'All Files (*.*)'}, "MultiSelect","off");
      if ~isempty(filename)
        app.LastFile = fullfile(pathname, filename);
        app.eFileName.Value = app.LastFile;
      end
    end

    % Button pushed function: RunButton
    function RunButtonPushed(app, event)
      % Loader
      Lines = readlines(app.eFileName.Value);
      Matrix = [];
      kk=1;
      for ii=4:length(Lines)
        Data = str2num(Lines{ii});
        if length(Data) > 1
          Matrix(kk,:) = Data;
          kk = kk + 1;
        end
      end
      nProbes = size(Matrix, 2) - 1;
      nPoints = size(Matrix, 1);
      if isempty(app.tCalibrations.Data) || ~isequal(size(app.tCalibrations.Data,1), nProbes)
        app.tCalibrations.Data = [];
        app.tCalibrations.Data(1:nProbes,1) = 1:nProbes;
        app.tCalibrations.Data(1:nProbes,2) = 0;
        app.tCalibrations.Data(1:nProbes,3) = 20 + (1:nProbes);
      end
      
      Calibration = repmat(app.tCalibrations.Data(1:nProbes,2)', nPoints,1);
      Position = app.tCalibrations.Data(1:nProbes,3);
      Matrix(:,2:end) = Matrix(:,2:end) + Calibration;
      
      % Processing
      FirstPoint = app.eFirstPoint.Value;
      Time = Matrix(FirstPoint:end,1);
      TData = Matrix(FirstPoint:end,2:end);
      fitE0 = @(x) x(1) - x(2)*exp(-Time * x(3));
      fitE1 = @(x) x(1) - x(2)*exp(-Time * x(3))- x(4)*exp(-Time * x(5));
      legendstrings = {};
      C = linspecer(size(Matrix, 2),'sequential');
      
      figure(2000); clf; hold on;
      ResultT = [];
      ResultM = [];
      app.tLocations.Data = zeros(8,3);
      
      for ii=1:size(TData, 2)
        P2 = mod(Position(ii), 10); P2 = max(min(P2,8),1);
        P1 = floor(Position(ii) / 10); P1 = max(min(P1,3),1);
        Trace = TData(:,ii);
        plot(Matrix(:,1), Matrix(:,ii+1), '.','color',C(ii,:), 'MarkerSize', 14)
        legendstrings{end+1} = sprintf('S%i-%i', P1, P2);
        
        switch 'exp'
          case 'exp'
            fsTa = @(x) sqrt(sum((fitE0(x) - Trace).^2));
            x = fminsearch(fsTa, [max(Trace),max(Trace),2/max(Time)],[]);
            plot(Time, fitE0(x), '-','color',C(ii,:))
            ResultT(ii) = 1/x(3);
            ResultM(ii) = x(1);
            legendstrings{end+1} = sprintf('S%i-%i T=%4.1f min   Tmax=%4.1f C', P1, P2, 1/x(3), x(1));
          case 'exp2'
            fsTa = @(x) sqrt(sum((fitE1(x) - Trace).^2));
            x = fminsearch(fsTa, [max(Trace),0,2/max(Time), max(Trace), 2/max(Time)],[]);
            plot(Time, fitE1(x), '-','color',C(ii,:))
            legendstrings{end+1} = sprintf('S%i T=%4.1f min   Tmax=%4.1f C', ii-1, min(abs(1/x(3)), abs(1/x(5))), x(1));
        end
        if contains(app.ddShow.Value, 'Max')
          app.tLocations.Data(P2,P1) =  ResultM(ii);
        else
          app.tLocations.Data(P2,P1) =  ResultT(ii);
        end
      end
      xlabel('Time (Min)','Fontsize',15) 
      ylabel('Temperature (C)','Fontsize',15)
      legend(legendstrings, 'Location','SouthEast','Fontsize',12);
      
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.ExpFitGUIUIFigure = uifigure('Visible', 'off');
      app.ExpFitGUIUIFigure.Name = 'ExpFitGUI';
      app.BrowseButton = uibutton(app.ExpFitGUIUIFigure, 'push');
      app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
      app.BrowseButton.Text = 'Browse';
      app.RunButton = uibutton(app.ExpFitGUIUIFigure, 'push');
      app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
      app.RunButton.Text = 'Run';
      app.TextfileEditFieldLabel = uilabel(app.ExpFitGUIUIFigure);
      app.TextfileEditFieldLabel.Text = 'Text file';
      app.eFileName = uieditfield(app.ExpFitGUIUIFigure, 'text');
      app.FirstPointEditFieldLabel = uilabel(app.ExpFitGUIUIFigure);
      app.FirstPointEditFieldLabel.Text = 'FirstPoint';
      app.eFirstPoint = uieditfield(app.ExpFitGUIUIFigure, 'numeric');
      app.eFirstPoint.Limits = [1 1000];
      app.eFirstPoint.ValueDisplayFormat = '%i';
      app.eFirstPoint.Value = 1;
      app.tCalibrations = uitable(app.ExpFitGUIUIFigure);
      app.tCalibrations.ColumnName = {'N'; 'T Offset'; 'Pos'};
      app.tCalibrations.RowName = {};
      app.tCalibrations.ColumnEditable = [false true true];
      app.CalibrationLabel = uilabel(app.ExpFitGUIUIFigure);
      app.CalibrationLabel.Text = 'Calibration';
      app.tLocations = uitable(app.ExpFitGUIUIFigure);
      app.tLocations.ColumnName = {'1'; '2'; '3'};
      app.tLocations.RowName = {};
      app.tLocations.ColumnEditable = [false false false];
      app.ddShow = uidropdown(app.ExpFitGUIUIFigure);
      app.ddShow.Items = {'Max', 'T1'};
      app.ddShow.Value = 'Max';
      % Show the figure after all components are created
      app.ExpFitGUIUIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = ExpFitGUI

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.ExpFitGUIUIFigure)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.ExpFitGUIUIFigure)
    end
  end
end