classdef stage3d < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Stage3DscangeneratorUIFigure  matlab.ui.Figure
        GenerateButton                matlab.ui.control.Button
        ScanrangemmSpinnerLabel       matlab.ui.control.Label
        ScanrangemmSpinner            matlab.ui.control.Spinner
        PointsSpinnerLabel            matlab.ui.control.Label
        PointsSpinner                 matlab.ui.control.Spinner
        FieldOffsetDropDownLabel      matlab.ui.control.Label
        FieldOffsetDropDown           matlab.ui.control.DropDown
        OffsetGEditFieldLabel         matlab.ui.control.Label
        OffsetGEditField              matlab.ui.control.NumericEditField
        CenterreadDropDownLabel       matlab.ui.control.Label
        CenterreadDropDown            matlab.ui.control.DropDown
        ShapeDropDownLabel            matlab.ui.control.Label
        ShapeDropDown                 matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            sz = app.ScanrangemmSpinner.Value * 0.001; % mm
            points = app.PointsSpinner.Value;
            
            range = linspace(-sz/2, sz/2, points); % mm
            [X, Y, Z] = meshgrid(range,range,range);
            OFF = zeros(size(X));
            out = [X(:),Y(:),Z(:),OFF(:)];
            
            switch app.ShapeDropDown.Value
                case 'grid'
                case 'cylinderY'
                    idx = X(:).^2 + Z(:).^2 <= (sz/2)^2;
                    out = out(idx,:);
            end
            
            % add center read points
            switch app.CenterreadDropDown.Value
                case '1'
                case '2'
                    out = [[0,0,0,0];out;[0,0,0,0]];
                otherwise
                    every_n = str2double(app.FieldOffsetDropDown.Value);
                    new_points = size(out,1)*(1 + 1/every_n);
                    for ii=0:(every_n+1):new_points-1
                        out   = [out(1:ii,:);[0,0,0,0];out(ii+1:end,:)];
                    end
                    out = [out;[0,0,0,0]];
            end
            
            % add read points with offset
            FieldOffset = app.OffsetGEditField.Value;
            switch app.FieldOffsetDropDown.Value
                case '1'
                case '2'
                    out = [[out(1,1:3),FieldOffset];out;[out(end,1:3),FieldOffset]];
                otherwise
                    every_n = str2double(app.FieldOffsetDropDown.Value);
                    new_points = size(out,1)*(1 + 1/every_n);
                    for ii=0:(every_n+1):new_points-1
                        out = [out(1:ii,:);[out(max(1,ii),1:3),FieldOffset];out(ii+1:end,:)];
                    end
                    out = [out;[out(end,1:3),FieldOffset]];
            end
            
            fid = fopen(sprintf('C:\\Temp\\test (%d).dat', size(out,1)), 'w');
            if fid >= 0
                for ii= 1:size(out,1)
                    fprintf(fid, '%-6.3f %-6.3f %-6.3f %-6.3f\r\n', out(ii, 1), out(ii, 2), out(ii, 3), out(ii, 4));
                end              
                fclose(fid);
            else
                disp('Error openning file.');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Stage3DscangeneratorUIFigure = uifigure('Visible', 'off');
            app.Stage3DscangeneratorUIFigure.Name = 'Stage 3D scan generator';
            app.GenerateButton = uibutton(app.Stage3DscangeneratorUIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.ScanrangemmSpinnerLabel = uilabel(app.Stage3DscangeneratorUIFigure);
            app.ScanrangemmSpinnerLabel.Text = 'Scan range [mm]';
            app.ScanrangemmSpinner = uispinner(app.Stage3DscangeneratorUIFigure);
            app.ScanrangemmSpinner.Limits = [0 50];
            app.ScanrangemmSpinner.ValueDisplayFormat = '%d';
            app.ScanrangemmSpinner.Value = 10;
            app.PointsSpinnerLabel = uilabel(app.Stage3DscangeneratorUIFigure);
            app.PointsSpinnerLabel.Text = 'Points';
            app.PointsSpinner = uispinner(app.Stage3DscangeneratorUIFigure);
            app.PointsSpinner.Step = 2;
            app.PointsSpinner.Limits = [3 21];
            app.PointsSpinner.ValueDisplayFormat = '%d';
            app.PointsSpinner.Value = 5;
            app.FieldOffsetDropDownLabel = uilabel(app.Stage3DscangeneratorUIFigure);
            app.FieldOffsetDropDownLabel.Text = 'Field Offset';
            app.FieldOffsetDropDown = uidropdown(app.Stage3DscangeneratorUIFigure);
            app.FieldOffsetDropDown.Items = {'None', 'Before/After', 'Every 10', 'Every 20'};
            app.FieldOffsetDropDown.ItemsData = {'1', '2', '10', '20'};
            app.FieldOffsetDropDown.Value = '2';
            app.OffsetGEditFieldLabel = uilabel(app.Stage3DscangeneratorUIFigure);
            app.OffsetGEditFieldLabel.Text = 'Offset [G]';
            app.OffsetGEditField = uieditfield(app.Stage3DscangeneratorUIFigure, 'numeric');
            app.OffsetGEditField.Value = -15;
            app.CenterreadDropDownLabel = uilabel(app.Stage3DscangeneratorUIFigure);
            app.CenterreadDropDownLabel.Text = 'Center read';
            app.CenterreadDropDown = uidropdown(app.Stage3DscangeneratorUIFigure);
            app.CenterreadDropDown.Items = {'None', 'Before/After', 'Every 20', 'Every 50'};
            app.CenterreadDropDown.ItemsData = {'1', '2', '20', '50'};
            app.CenterreadDropDown.Value = '2';
            app.ShapeDropDownLabel = uilabel(app.Stage3DscangeneratorUIFigure);
            app.ShapeDropDownLabel.Text = 'Shape';
            app.ShapeDropDown = uidropdown(app.Stage3DscangeneratorUIFigure);
            app.ShapeDropDown.Items = {'Grid', 'Cylinder Y'};
            app.ShapeDropDown.ItemsData = {'grid', 'cylinderY'};
            app.ShapeDropDown.Value = 'grid';
            % Show the figure after all components are created
            app.Stage3DscangeneratorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stage3d

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Stage3DscangeneratorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Stage3DscangeneratorUIFigure)
        end
    end
end