classdef ANLPLG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ANLPluginUIFigure       matlab.ui.Figure
        tOutput                 matlab.ui.control.TextArea
        ButtonGroup             matlab.ui.container.ButtonGroup
        TwoExponentsButton      matlab.ui.control.RadioButton
        FutureoptionButton      matlab.ui.control.RadioButton
        FTButton                matlab.ui.control.RadioButton
        ExponentButton          matlab.ui.control.RadioButton
        LightDarkButton         matlab.ui.control.RadioButton
        NoneButton              matlab.ui.control.RadioButton
        TabGroup                matlab.ui.container.TabGroup
        PhaseTab                matlab.ui.container.Tab
        SkewSpinner             matlab.ui.control.Spinner
        SkewSpinnerLabel        matlab.ui.control.Label
        PhaseSpinner            matlab.ui.control.Spinner
        PhaseSpinnerLabel       matlab.ui.control.Label
        FittingTab              matlab.ui.container.Tab
        FirstPointSpinner       matlab.ui.control.Spinner
        FirstPointSpinnerLabel  matlab.ui.control.Label
    end


    properties (Access = public)
        Master % Description
    end

    methods (Access = public)
%-----------------------------------------------------------------
        function results = FileUpdate(app)
            results = '';
            if app.NoneButton == 1
            elseif app.LightDarkButton.Value == 1

                [Data, src] = LD(app);
                if size(src.y, 1) > 1
                    hand = guidata(app.Master);
                    hand.out = {};
                    hand.out{1} = src;
                    hand.out{1}.y = Data;
                    hand.out{1}.ax.Color = 'r';

                    [~,name] = fileparts(get(app.Master, 'FileName'));
                    eval([name '(''SetDataSource'', 2, hand)']);
                    results = 'OK';
                end
            elseif app.ExponentButton.Value == 1
                [Data, src] = LD(app);

                if size(src.y, 1) > 1
                    hand = guidata(app.Master);
                    hand.out = {};
                    hand.out{1} = src;
                    hand.out{1}.y = Data;
                    hand.out{1}.ax.Color = 'r';

                    FirstPoint = app.FirstPointSpinner.Value;
                    TT = hand.out{1}.ax.x(FirstPoint:end); TT=TT(:);
                    y = real(Data(FirstPoint:end)); y = y(:);

                    fit_recovery = @(x,t) x(1) - x(3)*exp(-t*x(2));

                    fit_fun = @(x) sqrt(sum((y - fit_recovery(x,TT)).^2));
                    opt = optimset('Display','off');
                    [est_TT, err] = fminsearch(fit_fun, [max(y),1/(max(TT)/5),(max(y)-min(y))], opt);
                    ShowMessage(app, sprintf('A-B*exp(-t/T)\nresidual=%5.4g\nA = %5.3g  B = %5.3g  T = %6.4f us',...
                        err, est_TT(1), est_TT(3), 1/est_TT(2)*1e6))

                    hand.out{2} = src;
                    hand.out{2}.ax.x = TT;
                    hand.out{2}.ax.y = 0;
                    hand.out{2}.ax.xlabel = src.ax.ylabel;
                    hand.out{2}.y = fit_recovery(est_TT, TT);
                    hand.out{2}.ax.Color = 'g';

                    [~,name] = fileparts(get(app.Master, 'FileName'));
                    eval([name '(''SetDataSource'', 2, hand)']);
                end
            elseif app.TwoExponentsButton.Value == 1
                [Data, src] = LD(app);

                if size(src.y, 1) > 1
                    hand = guidata(app.Master);
                    hand.out = {};
                    hand.out{1} = src;
                    hand.out{1}.y = Data;
                    hand.out{1}.ax.Color = 'r';

                    FirstPoint = app.FirstPointSpinner.Value;
                    TT = hand.out{1}.ax.x(FirstPoint:end); TT=TT(:);
                    y = real(Data(FirstPoint:end)); y = y(:);

                    fit_recovery = @(x,t) (x(1) - x(3)*exp(-t*x(2))) .* exp(-t*x(4)) + x(5);

                    fit_fun = @(x) sqrt(sum((y - fit_recovery(x,TT)).^2));
                    opt = optimset('Display','off');
                    [est_TT, err] = fminsearch(fit_fun, [(max(y)-min(y)),1/(max(TT)/5),(max(y)-min(y)), 1/(max(TT)/5), max(y)], opt);
                    residual = err;
                    ShowMessage(app, sprintf('(A - Bexp(-t/T1)) exp(-t/T2) + C\nresidual=%5.4g\nA = %5.3g  B = %5.3g  T1: %6.4f us\nC = %5.3g  T2 = %6.4f us',...
                        residual, est_TT(1), est_TT(3), 1/est_TT(2)*1e6,est_TT(5),1/est_TT(4)*1e6))

                    hand.out{2} = src;
                    hand.out{2}.ax.x = TT;
                    hand.out{2}.ax.y = 0;
                    hand.out{2}.ax.xlabel = src.ax.ylabel;
                    hand.out{2}.y = fit_recovery(est_TT, TT);
                    hand.out{2}.ax.Color = 'g';

                    [~,name] = fileparts(get(app.Master, 'FileName'));
                    eval([name '(''SetDataSource'', 2, hand)']);
                end
            elseif app.FTButton.Value == 1
                [Data, src] = LD(app);
                hand = guidata(app.Master);

                Data = Data - mean(Data);
                % zero filling
                Data(length(Data)*4) = 0;

                xx = src.ax.x;
                dx = mean(diff(xx));
                n = length(Data);
                hand.out = {};
                hand.out{1} = src;
                hand.out{1}.ax.x = 1/dx/n*linspace(-n/2+1, n/2, n)*1E-6;
                hand.out{1}.ax.xlabel = 'Frequency, MHz';
                hand.out{1}.ax.y = 0;
                hand.out{1}.y = fftshift(fft(Data));
                hand.out{1}.ax.Color = 'r';

                [~,xidx] = max(abs(hand.out{1}.y));
                [~,xidx2] = max(real(hand.out{1}.y));
                ShowMessage(app, sprintf('Max(abs): %f MHz\nMax(real): %f MHz\n', hand.out{1}.ax.x(xidx), hand.out{1}.ax.x(xidx2)))

                %                 [~,LOCS,W,P] = findpeaks(real(hand.out{1}.y));
                %                 maxP = max(P); LOCS = LOCS(P > maxP / 5);
                %                 ShowMessage(app, sprintf('Peaks: %f MHz\n', hand.out{1}.ax.x(LOCS)));

                [~,name] = fileparts(get(app.Master, 'FileName'));
                eval([name '(''SetDataSource'', 2, hand)']);

            elseif app.NoneButton == 1
                %   hand = guidata(handles.hh);
                %   src = hand.src;
                %   hand.out = {};
                %   hand.out{1} = src;
                %
                %   [~,name] = fileparts(get(handles.hh, 'FileName'));
                %   eval([name '(''SetDataSource'', 2, hand)']);
            end

        end

        function results = InitApp(app, hh)
            app.Master = hh;
        end
    end
%-----------------------------------------------------------------

    methods (Access = private)
%-----------------------------------------------------------------
        function [Data, src] = LD(app)
            hand = guidata(app.Master);
            src = hand.src;
            Phase = app.PhaseSpinner.Value;
            Skew  = app.SkewSpinner.Value;

            % LD experiment
            k = size(src.y);
            isLD = k(1)==2 && k(2) > 2; 
            
            if isLD
                Data = repmat(src.y(1,:),size(src.y, 1),1) - src.y;
                Data = Data(2:end,:);
                for ii=1:size(Data,1)
                    Data(ii,:) = real(Data(ii,:)) + 1i*imag(Data(ii,:))*Skew;
                    Data(ii,:) = Data(ii,:) * exp(-1i*Phase/180*pi);
                end
                Data = Data';

                src.ax.x = src.ax.y;
                src.ax.xlabel = src.ax.ylabel;
                src.ax.y = 0;
            else
                Data = src.y;
                Data = real(Data) + 1i * imag(Data) * Skew;
                Data = Data * exp(-1i*Phase/180*pi);
            end
        end

        function results = ShowMessage(app, message)
            app.tOutput.Value = message;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PhaseSpinner
        function PhaseSpinnerValueChanged(app, event)
            FileUpdate(app);
        end

        % Value changed function: SkewSpinner
        function SkewSpinnerValueChanged(app, event)
            FileUpdate(app);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            FileUpdate(app);
        end

        % Value changed function: FirstPointSpinner
        function FirstPointSpinnerValueChanged(app, event)
            FileUpdate(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ANLPluginUIFigure = uifigure('Visible', 'off');
            app.ANLPluginUIFigure.Name = 'ANL Plugin';
            app.TabGroup = uitabgroup(app.ANLPluginUIFigure);
            app.PhaseTab = uitab(app.TabGroup);
            app.PhaseTab.Title = 'Phase';
            app.PhaseSpinnerLabel = uilabel(app.PhaseTab);
            app.PhaseSpinnerLabel.Text = 'Phase';
            app.PhaseSpinner = uispinner(app.PhaseTab);
            app.PhaseSpinner.Limits = [-180 180];
            app.PhaseSpinner.ValueChangedFcn = createCallbackFcn(app, @PhaseSpinnerValueChanged, true);
            app.SkewSpinnerLabel = uilabel(app.PhaseTab);
            app.SkewSpinnerLabel.Text = 'Skew';
            app.SkewSpinner = uispinner(app.PhaseTab);
            app.SkewSpinner.Step = 0.01;
            app.SkewSpinner.Limits = [0.5 2];
            app.SkewSpinner.ValueChangedFcn = createCallbackFcn(app, @SkewSpinnerValueChanged, true);
            app.SkewSpinner.Value = 1;
            app.FittingTab = uitab(app.TabGroup);
            app.FittingTab.Title = 'Fitting';
            app.FirstPointSpinnerLabel = uilabel(app.FittingTab);
            app.FirstPointSpinnerLabel.Text = 'First Point';
            app.FirstPointSpinner = uispinner(app.FittingTab);
            app.FirstPointSpinner.Limits = [1 100000];
            app.FirstPointSpinner.ValueDisplayFormat = '%i';
            app.FirstPointSpinner.ValueChangedFcn = createCallbackFcn(app, @FirstPointSpinnerValueChanged, true);
            app.FirstPointSpinner.Value = 1;
            app.ButtonGroup = uibuttongroup(app.ANLPluginUIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.NoneButton = uiradiobutton(app.ButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.LightDarkButton = uiradiobutton(app.ButtonGroup);
            app.LightDarkButton.Text = 'Light-Dark';
            app.ExponentButton = uiradiobutton(app.ButtonGroup);
            app.ExponentButton.Text = 'Exponent';
            app.FTButton = uiradiobutton(app.ButtonGroup);
            app.FTButton.Text = 'FT';
            app.FutureoptionButton = uiradiobutton(app.ButtonGroup);
            app.FutureoptionButton.Text = 'Future option';
            app.TwoExponentsButton = uiradiobutton(app.ButtonGroup);
            app.TwoExponentsButton.Text = 'Two Exponents';
            app.tOutput = uitextarea(app.ANLPluginUIFigure);
            % Show the figure after all components are created
            app.ANLPluginUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ANLPLG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ANLPluginUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ANLPluginUIFigure)
        end
    end
end