classdef FitPlugin3 < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    figure1              matlab.ui.Figure
    uimenu1              matlab.ui.container.Menu
    mSimFFunc            matlab.ui.container.Menu
    EPRMRIMenu           matlab.ui.container.Menu
    mExpDecay            matlab.ui.container.Menu
    mExpDecay2Tau        matlab.ui.container.Menu
    mExpRecovery         matlab.ui.container.Menu
    mModulatedDecay      matlab.ui.container.Menu
    LineshapeMenu        matlab.ui.container.Menu
    mVoigtianFit         matlab.ui.container.Menu
    mLorentzian          matlab.ui.container.Menu
    mGaussian            matlab.ui.container.Menu
    PolynomialMenu       matlab.ui.container.Menu
    mPoly0               matlab.ui.container.Menu
    mPoly1               matlab.ui.container.Menu
    mPoly2               matlab.ui.container.Menu
    mSimUseProcessing    matlab.ui.container.Menu
    mShowDifference      matlab.ui.container.Menu
    mShowRange           matlab.ui.container.Menu
    mFit                 matlab.ui.container.Menu
    mFitAllMenu          matlab.ui.container.Menu
    GridLayout           matlab.ui.container.GridLayout
    bClearRange          matlab.ui.control.Button
    butFittAll           matlab.ui.control.Button
    bFitOnce             matlab.ui.control.Button
    bRemoveRange         matlab.ui.control.Button
    bAddRange            matlab.ui.control.Button
    txtRange             matlab.ui.control.Label
    eStringValue         matlab.ui.control.EditField
    LLabel               matlab.ui.control.Label
    eNumValue            matlab.ui.control.Spinner
    ddParameterStep      matlab.ui.control.DropDown
    lScript              matlab.ui.control.ListBox
    bChangeColor         matlab.ui.control.Button
    ddSliceSelector      matlab.ui.control.DropDown
    contextValue         matlab.ui.container.ContextMenu
    ReloadValueMenu      matlab.ui.container.Menu
    EnterExpressionMenu  matlab.ui.container.Menu
    ConverttoStringMenu  matlab.ui.container.Menu
    ConverttoNumberMenu  matlab.ui.container.Menu
    CopyToAllSlicesMenu  matlab.ui.container.Menu
  end


  properties (Access = private)
    KazanViewer % Description
    Fit % Description
    Color % Description
    EditorMode % Description
    AreaFit % Description
  end

  properties (Access = public)
    guid % Description
  end

  methods (Access = public)
    function FileUpdate(app)
      return
    end
  end

  methods (Access = private)
    %----------------------------------------------------------------------
    function res = Evaluate(app,script,x)
      function_string = '';
      % x is assigned automatically to workspace
      for k=1:length(script)
        if strcmp(strtrim(strtok(script{k},'=')),'f')
          function_string = script{k};
          function_string = strtrim(function_string(strfind(function_string, '=')+1:end));
        else
          try eval([script{k} ';']); catch; end
        end
      end
      res = eval(function_string);
    end

    %----------------------------------------------------------------------
    function PlotThis(app)
      if 0
        src = app.KazanViewer.GetData('psource');
      else
        src = app.KazanViewer.GetData('source');
      end
      kv_slice = app.KazanViewer.Get('slice');
      if app.mFitAllMenu.Checked
        slice = app.ddSliceSelector.Value;
        data_out = zeros(size(src.y));
      else
        slice = 1;
      end

      % Data
      out = {src};
      out{1}.ax.Color = app.Color;
      out{1}.ax.s = 0;
      out{1}.title = 'Src';

      if app.mShowRange.Checked
        out{end + 1}.ax   = src.ax;
        out{end}.ax.x = src.ax.x(~app.AreaFit);
        out{end}.y = src.y(~app.AreaFit,:);
        out{end}.ax.Marker='.';
        out{end}.ax.LineStyle='none';
        out{end}.ax.Color = [0.75,0.75,0.75];
        out{end}.title = 'Ignore';
        out{end}.ax.s = 0;
      end

      % Result
      if app.mFitAllMenu.Checked
        for ii=1:length(app.Fit)
          if ~isempty(app.Fit{ii}.y)
            data_out(:, ii) = app.Fit{ii}.y;
          end
        end

        out{end + 1}.ax = src.ax;
        out{end}.y = data_out;
        out{end}.ax.Color = 'r';
        out{end}.ax.s = 0;
        out{end}.title = 'Fit';

        % Data - result
        if app.mShowDifference.Checked
          out{end+1}.ax = src.ax;
          out{end}.y = src.y - data_out;
          out{end}.ax.Color = 'g';
          out{end}.ax.s = 0;
          out{end}.title = 'Diff';
        end
      else
        if ~isempty(app.Fit{slice}.y)
          out{end + 1}.ax = src.ax;
          out{end}.y = app.Fit{slice}.y;
          out{end}.ax.Color = 'r';
          out{end}.ax.s = 0;
          out{end}.title = 'Fit';

          % Data - result
          if app.mShowDifference.Checked
            out{end+1}.ax = src.ax;
            out{end}.y = src.y - app.Fit{slice}.y;
            out{end}.ax.Color = 'g';
            out{end}.ax.s = 0;
            out{end}.title = 'Diff';
          end
        end
      end

      %       states = {'off', 'on'};
      %       st = get(handles.mSimDif, 'Checked');
      %       check = find(strcmp(states, st)) -1;

      app.KazanViewer.SetData('output', out);
    end

    %----------------------------------------------------------------------
    function UpdateFitList(app)
      str = cell(length(app.Fit),1);
      for ii=1:length(app.Fit)
        str{ii} = num2str(ii);
      end
      app.ddSliceSelector.Items = str;
      app.ddSliceSelector.ItemsData = 1:length(app.Fit);
    end

    %----------------------------------------------------------------------
    function UpdateScriptList(app)
      if app.mFitAllMenu.Checked
        n = app.ddSliceSelector.Value;
      else
        n = 1;
      end
      app.lScript.Items = app.Fit{n}.Script;
      app.lScript.ItemsData = 1:length(app.lScript.Items);
    end
  end


  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, caller, guid)
      app.KazanViewer = caller;
      app.guid = guid;
      app.Color = [0,0,1];

      app.ddParameterStep.Items = {'1e-7', '1e-6', '1e-5', '1e-4', '1e-3', '0.001', '0.1', '1', '10', '100', '1000', '1e+4', '1e+5', '1e+6'};
      app.ddParameterStep.ItemsData = [1e-7, 1e-6, 1e-5, 1e-4, 1e-3, 1e-2, 0.1, 1, 10, 100, 1e+3, 1e+4, 1e+5, 1e+6];
      app.ddParameterStep.Value = 1.0;

      app.Fit{1}.Script = {'f = a*exp(-x/t)';'a = 1'; 't = 2e-6'};
      app.Fit{1}.y = [];

      app.UpdateFitList();
      app.UpdateScriptList();
      app.FileUpdate();
    end

    % Button pushed function: bChangeColor
    function bChangeColor_Callback(app, event)
      Col = uisetcolor(app.Color);
      if length(Col)== 1, return; end
      app.Color = Col;
      app.PlotThis();
    end

    % Button pushed function: butFittAll
    function butFittAll_Callback(app, event)
      if ~app.mFitAllMenu.Checked, app.KazanViewer.Set('message', 'Select: Fit -> FitAll'); return; end

      if app.mSimUseProcessing.Checked
        src = app.KazanViewer.GetData('psource');
      else
        src = app.KazanViewer.GetData('source');
      end
      if length(src.ax.x) ~= length(app.AreaFit)
        app.AreaFit = true(size(src.ax.x));
      end

      for ii=1:length(app.Fit)
        Script = app.Fit{ii}.Script;
        x = src.ax.x(app.AreaFit);
        y = src.y(app.AreaFit,ii);

        %       [ss, ind] = max(size(y));
        %       if ind == 2, x = x.'; end
        %       num = get(handles.popSel, 'Value');

        tic
        [NewScript]= kv_autofit(Script, x, real(y), []);
        a = toc;
        app.KazanViewer.Set('message', sprintf('Computations: slide %i %f sec', ii, a));
        %       if strcmp(selec, '(:, :)'), sel = ''; else sel = selec; end
        %       eval(['handles.sim.y', sel,'= yres',trans,';']);

        app.Fit{ii}.Script = NewScript;
        app.Fit{ii}.y = app.Evaluate(NewScript, src.ax.x);
      end

      app.UpdateScriptList();
      PlotThis(app);
    end

    % Button pushed function: bFitOnce
    function bFitOnceSelected(app, event)
      if app.mFitAllMenu.Checked
        selected_set = app.ddSliceSelector.Value;
        my_slide = app.ddSliceSelector.Value;
      else
        selected_set = 1;
        my_slide = app.KazanViewer.Get('slice');
      end
      Script = app.Fit{selected_set}.Script;
      if app.mSimUseProcessing.Checked
        src = app.KazanViewer.GetData('psource');
      else
        src = app.KazanViewer.GetData('source');
      end

      if length(src.ax.x) ~= length(app.AreaFit)
        app.AreaFit = true(size(src.ax.x));
      end
      x = src.ax.x(app.AreaFit);
      y = src.y(app.AreaFit,my_slide);
      %       [ss, ind] = max(size(y));
      %       if ind == 2, x = x.'; end
      %       num = get(handles.popSel, 'Value');
      %
      tic
      [NewScript]= kv_autofit(Script, x, real(y), []);
      a = toc;
      app.KazanViewer.Set('message', sprintf('Computations: %f sec', a));
      %       if strcmp(selec, '(:, :)'), sel = ''; else sel = selec; end
      %       eval(['handles.sim.y', sel,'= yres',trans,';']);

      app.Fit{selected_set}.Script = NewScript;
      app.Fit{selected_set}.y = app.Evaluate(NewScript, src.ax.x);
      app.UpdateScriptList();
      PlotThis(app);
    end

    % Menu selected function: mFitAllMenu
    function FitOneOrAllChanged(app, event)
      app.mFitAllMenu.Checked = ~app.mFitAllMenu.Checked;

      src = app.KazanViewer.GetData('source');
      n = size(src.y,2);
      if app.mFitAllMenu.Checked % Fit all
        if length(app.Fit) ~= n
          MyFit = app.Fit{1};
          for ii=1:n
            app.Fit{ii} = MyFit;
          end
        end
      else % Fit one
        if length(app.Fit) ~= 1
          app.Fit = app.Fit(1);
        end
      end
      app.UpdateFitList();
    end

    % Value changed function: lScript
    function lScript_Callback(app, event)

      Res = app.lScript.Items{app.lScript.Value};
      [name, str1] = strtok(Res, '=');
      str2 = [strtrim(str1(2:end)), ' '];

      if strcmp(strtrim(name), 'f')
        app.eStringValue.Enable = 1;
        app.eStringValue.Value = str2;
        app.eNumValue.Enable = 0;
        app.EditorMode = 'function';
        app.LLabel.Text = 'F';
      elseif str2(1)==''''
        app.eStringValue.Enable = 1;
        primes = strfind(str2, '''');
        if size(primes, 2) < 2, primes(2) = size(str2, 2); end
        app.eStringValue.Value = str2(2:(primes(2)-1));
        app.eNumValue.Enable = 0;
        app.LLabel.Text = 'S';
        app.EditorMode = 'string';
      else
        app.eNumValue.Enable = 1;
        app.eNumValue.Value = str2double(str2);
        app.eStringValue.Enable = 0;
        app.LLabel.Text = 'V';
        app.EditorMode = 'value';
      end
    end

    % Menu selected function: mSimUseProcessing
    function mSimUseProcessing_Callback(app, event)
      app.mSimUseProcessing.Checked = ~app.mSimUseProcessing.Checked;
      app.PlotThis();
    end

    % Menu selected function: mShowDifference
    function mShowDifference_Callback(app, event)
      app.mShowDifference.Checked = ~app.mShowDifference.Checked;
      app.PlotThis();
    end

    % Menu selected function: mShowRange
    function mShowRange_Callback(app, event)
      app.mShowRange.Checked = ~app.mShowRange.Checked;
      app.PlotThis();
    end

    % Menu selected function: mExpDecay, mExpDecay2Tau, 
    % mExpRecovery, mGaussian, mLorentzian, mModulatedDecay, 
    % mPoly0, mPoly1, mPoly2, mSimFFunc, mVoigtianFit
    function SelectFunctionChanged(app, event)
      switch event.Source
        case app.mSimFFunc
          % Create GUIDE-style callback args - Added by Migration Tool
          [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

          Res = get(handles.list, 'String');

          fittingbox_func(handles);
          uiwait(handles.figure1);
          Script = get(handles.list, 'UserData');
          if isempty(Script), return; end
          %%%% find strings with additional parameters %%%
          for count = 1:length(Res)
            if length(Res{count})>6
              if strcmp(Res{count}(1:6), 'FitRng')
                Script{end+1} = Res{count};
              end
            end
          end
        case app.mExpDecay
          Script = {'f = a*exp(-x/t)';'a = 1'; 't = 2e-6'};
        case app.mExpDecay2Tau
          Script = {'f = a*exp(-2*x/t)';'a = 1'; 't = 2e-6'};
        case app.mExpRecovery
          Script = {'f = a-b*exp(-x/t)';'a = 1';'b = 1'; 't = 2e-6'};
        case app.mModulatedDecay
          Script = {'f = a*exp(-x/t).*cos(x*2*pi*F+Ph)';'a = 1';'t = 2e-6';'F = 1e6';'Ph = 0'};
        case app.mVoigtianFit
          Script = {'f = a*kv3_lshape(x,x0,lwG,lwL) ';'a = 1';'x0 = 0';'lwG = 1';'lwL = 1'};
        case app.mLorentzian
          Script = {'f = a*lorentzian(x,x0,lwL) ';'a = 1';'x0 = 0'; 'lwL = 1'};
        case app.mGaussian
          Script = {'f = a*gaussian(x,x0,lwG) ';'a = 1';'x0 = 0';'lwG = 1'};
        case app.mPoly0
          Script = {'f = x0';'x0 = 0'};
        case app.mPoly1
          Script = {'f = x*x1 + x0';'x0 = 0'; 'x1 = 0'};
        case app.mPoly2
          Script = {'f = x.*x*x2 + x*x1 + x0';'x0 = 0'; 'x1 = 0'; 'x2 = 0'};
      end
      for ii=1:length(app.Fit)
        app.Fit{ii}.Script = Script;
      end
      app.UpdateScriptList();

    end

    % Close request function: figure1
    function figure1CloseRequest(app, event)
      try app.KazanViewer.MakePlugins('close', app); catch; end
      delete(app);
    end

    % Size changed function: figure1
    function figure1SizeChanged(app, event)
      standard_size = [70   669   265   365];
      fig_size = app.figure1.Position;
      scaling_factor = min(fig_size(3:4) ./ standard_size(3:4)) * 1.2;

      if ~isempty(app.KazanViewer)
        Font = app.KazanViewer.Get('font');
        font_size = Font * scaling_factor;
        set(findall(app.figure1, 'Type', 'UIButton'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UIStateButton'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UISpinner'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UILabel'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UICheckBox'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UIEdit'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UIDropDown'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UIListBox'), 'FontSize', font_size);
      end
    end

    % Callback function: ConverttoNumberMenu, ConverttoStringMenu, 
    % EnterExpressionMenu, ReloadValueMenu, ddParameterStep, 
    % eNumValue, eStringValue
    function EditValueChanged(app, event)
      NewScript = app.lScript.Items;
      if app.mFitAllMenu.Checked
        selected_set = app.ddSliceSelector.Value;
      else
        selected_set = 1;
      end
      ActiveString = NewScript{app.lScript.Value};
      [name] = strtok(ActiveString, '=');

      switch event.Source
        case app.ddParameterStep
          app.eNumValue.Step = app.ddParameterStep.Value;
          return;
        case app.ReloadValueMenu
          app.lScript_Callback([]);
          return;
        case app.ConverttoNumberMenu
          NewScript{app.lScript.Value} = [name ' = 0.0'];
        case app.ConverttoStringMenu
          NewScript{app.lScript.Value} = [name ' = ''string'''];
        case app.EnterExpressionMenu
          NewScript{app.lScript.Value} = [name ' = ' num2str(125)];
        otherwise
          if contains(app.EditorMode, 'function')
            NewScript{app.lScript.Value} = ['f = ' strtrim(app.eStringValue.Value)];
          elseif contains(app.EditorMode, 'string')
            NewScript{app.lScript.Value} = [name ' = ''', strtrim(app.eStringValue.Value), ''''];
          elseif contains(app.EditorMode, 'value')
            NewScript{app.lScript.Value} = [name ' = ' num2str(app.eNumValue.Value)];
          end
      end
      NewScript2 = {};
      %%%%%%%%%%%%% evaluate all parameters %%%%%%%%%%%%%%%%%%%%%%
      for k=1:length(NewScript)
        if strcmp(strtrim(strtok(NewScript{k},'=')),'f')
          NewScript2{end+1} = NewScript{k};
        else
          try eval([NewScript{k} ';']); catch; end
        end
      end
      %%%%%%%%%%%%% collect tokens %%%%%%%%%%%%%%%%%%%%%%
      tokens = kv_autofit(NewScript{1});
      for ii=1:length(tokens)
        if ~exist(tokens{ii}, 'var'), eval([tokens{ii}, '= 0.0']); end
        val = eval(tokens{ii});
        switch whos(tokens{ii}).class
          case 'double'
            NewScript2{end+1} = [tokens{ii}, ' = ', num2str(val)];
          case 'char'
            NewScript2{end+1} = [tokens{ii}, ' = ''', val,''''];
        end
      end
      app.Fit{selected_set}.Script = NewScript2;

      %%%%%%%%%%%%% compute results %%%%%%%%%%%%%%%%%%%%%%
      src = app.KazanViewer.GetData('source');
      app.UpdateScriptList();
      app.Fit{selected_set}.y = app.Evaluate(NewScript2, src.ax.x);
      app.PlotThis();
    end

    % Button pushed function: bAddRange, bClearRange, bRemoveRange
    function RangeChanged(app, event)
      src = app.KazanViewer.GetData('source');
      x = src.ax.x;
      if isempty(app.AreaFit)
        app.AreaFit = true(length(x),1);
      end
      if size(app.AreaFit,1) > length(x)
        app.AreaFit = app.AreaFit(1:length(x));
      elseif size(app.AreaFit,1) < length(x)
        app.AreaFit(length(x)) = true;
      end
      switch event.Source
        case app.bClearRange
          app.AreaFit = true(size(src.ax.x));
        case app.bAddRange
          app.KazanViewer.Set('message', 'Select the beginning and the end of the range');
          pos = app.KazanViewer.Ginput(2);
          pos1 = sort(pos(:,1));

          app.AreaFit(x > pos1(1) & x < pos1(2)) = true;
          app.PlotThis();
        case app.bRemoveRange
          app.KazanViewer.Set('message','Select the beginning and the end of the range');
          pos = app.KazanViewer.Ginput(2);
          pos1 = sort(pos(:,1));

          app.AreaFit(x > pos1(1) & x < pos1(2)) = false;
          app.PlotThis();
      end
    end

    % Value changed function: ddSliceSelector
    function ddSliceSelectorValueChanged(app, event)
      if app.mFitAllMenu.Checked
        app.KazanViewer.Set('slice', app.ddSliceSelector.Value);
      end
      app.UpdateScriptList();
      app.PlotThis();
    end

    % Menu selected function: CopyToAllSlicesMenu
    function CopyToAllSlicesMenuSelected(app, event)
      if app.mFitAllMenu.Checked
        slice = app.ddSliceSelector.Value;
        for ii=1:length(app.Fit)
          app.Fit{ii}.Script = app.Fit{slice}.Script;
        end
      end
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.figure1 = uifigure('Visible', 'off');
      app.figure1.Name = 'Fitting';
      app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
      app.figure1.SizeChangedFcn = createCallbackFcn(app, @figure1SizeChanged, true);
      app.figure1.Tag = 'figure1';
      app.uimenu1 = uimenu(app.figure1);
      app.uimenu1.Text = 'Simulation';
      app.uimenu1.Tag = 'uimenu1';
      app.mSimFFunc = uimenu(app.uimenu1);
      app.mSimFFunc.MenuSelectedFcn = createCallbackFcn(app, @SelectFunctionChanged, true);
      app.mSimFFunc.Text = 'Fit Function ...';
      app.mSimFFunc.Tag = 'mSimFFunc';
      app.EPRMRIMenu = uimenu(app.uimenu1);
      app.EPRMRIMenu.Text = 'EPR/MRI';
      app.mExpDecay = uimenu(app.EPRMRIMenu);
      app.mExpDecay.MenuSelectedFcn = createCallbackFcn(app, @SelectFunctionChanged, true);
      app.mExpDecay.Text = 'T2*: exp(-x/t)';
      app.mExpDecay2Tau = uimenu(app.EPRMRIMenu);
      app.mExpDecay2Tau.MenuSelectedFcn = createCallbackFcn(app, @SelectFunctionChanged, true);
      app.mExpDecay2Tau.Text = 'T2*: exp(-x/t)';
      app.mExpRecovery = uimenu(app.EPRMRIMenu);
      app.mExpRecovery.MenuSelectedFcn = createCallbackFcn(app, @SelectFunctionChanged, true);
      app.mExpRecovery.Text = 'T1: 1-2*exp(-x/t)';
      app.mModulatedDecay = uimenu(app.EPRMRIMenu);
      app.mModulatedDecay.MenuSelectedFcn = createCallbackFcn(app, @SelectFunctionChanged, true);
      app.mModulatedDecay.Text = 'T2* mod: cos*exp';
      app.LineshapeMenu = uimenu(app.uimenu1);
      app.LineshapeMenu.Text = 'Lineshape';
      app.mVoigtianFit = uimenu(app.LineshapeMenu);
      app.mVoigtianFit.MenuSelectedFcn = createCallbackFcn(app, @SelectFunctionChanged, true);
      app.mVoigtianFit.Text = 'Voigtian';
      app.mLorentzian = uimenu(app.LineshapeMenu);
      app.mLorentzian.MenuSelectedFcn = createCallbackFcn(app, @SelectFunctionChanged, true);
      app.mLorentzian.Text = 'Lorentzian';
      app.mGaussian = uimenu(app.LineshapeMenu);
      app.mGaussian.MenuSelectedFcn = createCallbackFcn(app, @SelectFunctionChanged, true);
      app.mGaussian.Text = 'Gaussian';
      app.PolynomialMenu = uimenu(app.uimenu1);
      app.PolynomialMenu.Text = 'Polynomial';
      app.mPoly0 = uimenu(app.PolynomialMenu);
      app.mPoly0.MenuSelectedFcn = createCallbackFcn(app, @SelectFunctionChanged, true);
      app.mPoly0.Text = 'x0';
      app.mPoly1 = uimenu(app.PolynomialMenu);
      app.mPoly1.MenuSelectedFcn = createCallbackFcn(app, @SelectFunctionChanged, true);
      app.mPoly1.Text = 'x1,x0';
      app.mPoly2 = uimenu(app.PolynomialMenu);
      app.mPoly2.MenuSelectedFcn = createCallbackFcn(app, @SelectFunctionChanged, true);
      app.mPoly2.Text = 'x2,x1,x0';
      app.mSimUseProcessing = uimenu(app.uimenu1);
      app.mSimUseProcessing.MenuSelectedFcn = createCallbackFcn(app, @mSimUseProcessing_Callback, true);
      app.mSimUseProcessing.Separator = 'on';
      app.mSimUseProcessing.Checked = 'on';
      app.mSimUseProcessing.Text = 'Source processing';
      app.mSimUseProcessing.Tag = 'mSimAgr';
      app.mShowDifference = uimenu(app.uimenu1);
      app.mShowDifference.MenuSelectedFcn = createCallbackFcn(app, @mShowDifference_Callback, true);
      app.mShowDifference.Checked = 'on';
      app.mShowDifference.Text = 'Show Difference';
      app.mShowDifference.Tag = 'mSimDif';
      app.mShowRange = uimenu(app.uimenu1);
      app.mShowRange.MenuSelectedFcn = createCallbackFcn(app, @mShowRange_Callback, true);
      app.mShowRange.Checked = 'on';
      app.mShowRange.Text = 'Show Range';
      app.mShowRange.Tag = 'mSimShowBds';
      app.mFit = uimenu(app.figure1);
      app.mFit.Text = 'Fit';
      app.mFit.Tag = 'mLoadOneByOne';
      app.mFitAllMenu = uimenu(app.mFit);
      app.mFitAllMenu.MenuSelectedFcn = createCallbackFcn(app, @FitOneOrAllChanged, true);
      app.mFitAllMenu.Text = 'Fit All';
      app.GridLayout = uigridlayout(app.figure1);
      app.ddSliceSelector = uidropdown(app.GridLayout);
      app.ddSliceSelector.Items = {'1'};
      app.ddSliceSelector.ValueChangedFcn = createCallbackFcn(app, @ddSliceSelectorValueChanged, true);
      app.ddSliceSelector.Tag = 'popSel';
      app.ddSliceSelector.Value = '1';
      app.bChangeColor = uibutton(app.GridLayout, 'push');
      app.bChangeColor.ButtonPushedFcn = createCallbackFcn(app, @bChangeColor_Callback, true);
      app.bChangeColor.Tag = 'butCol';
      app.bChangeColor.Text = '';
      app.lScript = uilistbox(app.GridLayout);
      app.lScript.Items = {'f = polyfit(x, y, n)', 'n = 2'};
      app.lScript.ValueChangedFcn = createCallbackFcn(app, @lScript_Callback, true);
      app.lScript.Tag = 'list';
      app.lScript.Value = 'f = polyfit(x, y, n)';
      app.ddParameterStep = uidropdown(app.GridLayout);
      app.ddParameterStep.Items = {'1e-6', '1e-5', '1e-4', '1e-3', '1e-2', '0.1', '1', '10', '100', '1e+3', '1e+4', '1e+5', '1e+6'};
      app.ddParameterStep.ValueChangedFcn = createCallbackFcn(app, @EditValueChanged, true);
      app.ddParameterStep.Tag = 'popup';
      app.ddParameterStep.Value = '0.1';
      app.eNumValue = uispinner(app.GridLayout);
      app.eNumValue.ValueChangedFcn = createCallbackFcn(app, @EditValueChanged, true);
      app.LLabel = uilabel(app.GridLayout);
      app.LLabel.Text = 'L';
      app.eStringValue = uieditfield(app.GridLayout, 'text');
      app.eStringValue.ValueChangedFcn = createCallbackFcn(app, @EditValueChanged, true);
      app.txtRange = uilabel(app.GridLayout);
      app.txtRange.Tag = 'txtRange';
      app.txtRange.Text = 'Fitting range';
      app.bAddRange = uibutton(app.GridLayout, 'push');
      app.bAddRange.ButtonPushedFcn = createCallbackFcn(app, @RangeChanged, true);
      app.bAddRange.Tag = 'pbAddRange';
      app.bAddRange.Tooltip = 'Add Range';
      app.bAddRange.Text = '+';
      app.bRemoveRange = uibutton(app.GridLayout, 'push');
      app.bRemoveRange.ButtonPushedFcn = createCallbackFcn(app, @RangeChanged, true);
      app.bRemoveRange.Tag = 'pbKillRange';
      app.bRemoveRange.Tooltip = 'Delete Range';
      app.bRemoveRange.Text = '-';
      app.bFitOnce = uibutton(app.GridLayout, 'push');
      app.bFitOnce.ButtonPushedFcn = createCallbackFcn(app, @bFitOnceSelected, true);
      app.bFitOnce.Tag = 'butFitt';
      app.bFitOnce.Text = 'Fit Slice';
      app.butFittAll = uibutton(app.GridLayout, 'push');
      app.butFittAll.ButtonPushedFcn = createCallbackFcn(app, @butFittAll_Callback, true);
      app.butFittAll.Tag = 'butFittAll';
      app.butFittAll.Text = {'Fit All Slices'; ''};
      app.bClearRange = uibutton(app.GridLayout, 'push');
      app.bClearRange.ButtonPushedFcn = createCallbackFcn(app, @RangeChanged, true);
      app.bClearRange.Text = 'Clear';
      app.contextValue = uicontextmenu(app.figure1);
      app.ReloadValueMenu = uimenu(app.contextValue);
      app.ReloadValueMenu.MenuSelectedFcn = createCallbackFcn(app, @EditValueChanged, true);
      app.ReloadValueMenu.Text = 'ReloadValue';
      app.EnterExpressionMenu = uimenu(app.contextValue);
      app.EnterExpressionMenu.MenuSelectedFcn = createCallbackFcn(app, @EditValueChanged, true);
      app.EnterExpressionMenu.Separator = 'on';
      app.EnterExpressionMenu.Text = 'Enter Expression';
      app.ConverttoStringMenu = uimenu(app.contextValue);
      app.ConverttoStringMenu.MenuSelectedFcn = createCallbackFcn(app, @EditValueChanged, true);
      app.ConverttoStringMenu.Text = 'Convert to String';
      app.ConverttoNumberMenu = uimenu(app.contextValue);
      app.ConverttoNumberMenu.MenuSelectedFcn = createCallbackFcn(app, @EditValueChanged, true);
      app.ConverttoNumberMenu.Text = 'Convert to Number';
      app.CopyToAllSlicesMenu = uimenu(app.contextValue);
      app.CopyToAllSlicesMenu.MenuSelectedFcn = createCallbackFcn(app, @CopyToAllSlicesMenuSelected, true);
      app.CopyToAllSlicesMenu.Separator = 'on';
      app.CopyToAllSlicesMenu.Text = 'Copy To All Slices';
      % Assign app.contextValue
      app.lScript.ContextMenu = app.contextValue;
      % Show the figure after all components are created
      app.figure1.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = FitPlugin3(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.figure1)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.figure1)
    end
  end
end