classdef MinusBox3 < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    figure1          matlab.ui.Figure
    uimenu1          matlab.ui.container.Menu
    mShowReference   matlab.ui.container.Menu
    mShowTrace       matlab.ui.container.Menu
    mShowResult      matlab.ui.container.Menu
    mAbout           matlab.ui.container.Menu
    GridLayout       matlab.ui.container.GridLayout
    eShiftResult     matlab.ui.control.Spinner
    eZoomY           matlab.ui.control.Spinner
    eZoomX           matlab.ui.control.Spinner
    eShiftY          matlab.ui.control.Spinner
    eShiftX          matlab.ui.control.Spinner
    cbInverseResult  matlab.ui.control.CheckBox
    cbShiftResult    matlab.ui.control.CheckBox
    text6            matlab.ui.control.Label
    pmSlice          matlab.ui.control.DropDown
    bResetZoom       matlab.ui.control.Button
    pmZoomY          matlab.ui.control.DropDown
    pmZoomX          matlab.ui.control.DropDown
    text3            matlab.ui.control.Label
    text2            matlab.ui.control.Label
    pbToRef          matlab.ui.control.Button
    pbResetShift     matlab.ui.control.Button
    text4            matlab.ui.control.Label
    pmShiftY         matlab.ui.control.DropDown
    pmShiftX         matlab.ui.control.DropDown
    text1            matlab.ui.control.Label
    cbSimpleMath     matlab.ui.control.CheckBox
    cbDivide         matlab.ui.control.CheckBox
    cbMultiply       matlab.ui.control.CheckBox
    cbMinus          matlab.ui.control.CheckBox
    cbPlus           matlab.ui.control.CheckBox
    bSetReference    matlab.ui.control.Button
  end


  properties (Access = private)
    KazanViewer % Description
    Ref % reference spectrum
    Parameter % Description
    operator % Description
  end

  properties (Access = public)
    guid % Description
  end

  %----------------------------------------------------------------------
  methods (Access = public)
    function FileUpdate(app)
      %       if handles.fileupdate
      %         app.PlotThis();
      %       end
    end
  end

  %----------------------------------------------------------------------
  methods (Access = private)
    %----------------------------------------------------------------------
    function PlotThis(app)
      if app.mShowReference.Checked
        src = app.KazanViewer.GetData('psource');
      else
        src = app.KazanViewer.GetData('source');
      end

      isref = ~app.cbSimpleMath.Value;
      if isref && isempty(app.Ref), error('Please set the reference.'); end

      out = {};

      tmpSec = src;
      if ~isref
        tmpRef = src;
      else
        tmpRef = app.Ref;
      end

      %       if h.process
      %         [ tmpRef.y,  tmpRef.ax] = processing( h.Ref.y,  h.Ref.ax);
      %         [ tmpSec.y,  tmpSec.ax] = processing( hand.src.y,  hand.src.ax);
      %       else
      %         tmpRef.ax.filt = '';
      %         tmpRef.ax.diff = '';
      %         tmpSec.ax.filt = '';
      %         tmpSec.ax.diff = '';
      %       end
      tmpRef.ax.Color = [0, 0, 1];
      tmpRef.title = 'Ref';

      tmpSec.ax.x =tmpSec.ax.x * app.eZoomX.Value + app.eShiftX.Value;
      for ii=1:size(tmpSec.y,2)
        tmpSec.y(:,ii) = tmpSec.y(:,ii)*app.Parameter{ii}.ZoomY + app.Parameter{1}.ShiftY;
      end
      tmpSec.ax.Color = [1, 0, 0];
      tmpSec.ax.s = 0;
      tmpSec.ax.dx= 0;
      tmpSec.title = 'Op 2';


      if isref
        % adjust dimesiones
        [szRefx,szRefy] = size(tmpRef.y);
        [szSecx,szSecy] = size(tmpSec.y);

        if szRefy ~= szSecy
          if szRefy == 1
            tmpRef.y(:,1:szSecy)=  tmpRef.y(:,ones(szSecy,1));
            szRefy = szSecy;
            tmpRef.ax.y = tmpSec.ax.y;
          end
          if szSecy == 1
            tmpSec.y(:,1:szRefy)=  tmpSec.y(:,ones(szRefy,1));
            szSecy = szRefy;
            tmpSec.ax.y = tmpRef.ax.y;
          end
        end

        br = 0;
        if (szRefx==szSecx)
          if sum(tmpRef.ax.x - tmpSec.ax.x)
            br = 1;
          end
        else
          br=1;
        end
        if br
          x1 = max([tmpRef.ax.x(1), tmpSec.ax.x(1)]);
          x2 = min([tmpRef.ax.x(end), tmpSec.ax.x(end)]);
          npoints  = size(tmpRef.ax.x, 1);
          xax = linspace(x1, x2, npoints).';
          for ii=1:size(tmpRef.y, 2)
            tmp1(:,ii) = spline(tmpRef.ax.x, tmpRef.y(:,ii), xax);
            tmp2(:,ii) = spline(tmpSec.ax.x, tmpSec.y(:,ii), xax);
          end
        else
          tmp1 = tmpRef.y;
          tmp2 = tmpSec.y;
          xax = tmpRef.ax.x;
        end
        eval(['y = tmp1', app.operator, 'tmp2;']);
      else
        y = tmpSec.y;
        xax = tmpSec.ax.x;
      end

      tmpRes = tmpRef;
      tmpRes.ax.x =xax;
      tmpRes.y = y;
      if app.cbInverseResult.Value, tmpRes.y = -tmpRes.y; end
      tmpRes.ax.Color = [0, 1, 0]*0.8;

      tmpRes.ax.s = 0;
      if app.cbShiftResult.Value, tmpRes.ax.s = app.eShiftResult.Value; end
      tmpRes.ax.dx= 0;
      tmpRes.title = 'Result';
      if app.mShowReference.Checked, out{end+1} = tmpRef; end
      if app.mShowTrace.Checked && isref, out{end+1} = tmpSec; end
      if app.mShowResult.Checked, out{end+1} = tmpRes; end

      app.KazanViewer.SetData('output', out);
    end

    %----------------------------------------------------------------------
    function sl = getslicestring(app, handles)
      n = get(handles.pmSlice, 'Value');
      if n==1, sl=1:length(get(handles.pmSlice, 'String'));
      else, sl = n-1;
      end
    end

    %----------------------------------------------------------------------
    function newset = UpdateSet(app, newset)
      newset.ShiftX = safeget(newset, 'ShiftX', 0);
      newset.ShiftY = safeget(newset, 'ShiftY', 0);
      newset.ZoomX = safeget(newset, 'ZoomX', 1);
      newset.ZoomY = safeget(newset, 'ZoomY', 1);
    end

    %----------------------------------------------------------------------
    function UpdateSlice(app)
      str{1} = 'All';
      if ~isempty(app.Ref)
        n = size(app.Ref.y, 2);
        for ii=1:n, str{ii+1}=num2str(ii); end
        while length(app.Parameter) < n, app.Parameter{end+1} = app.Parameter{1}; end
        app.bSetReference.BackgroundColor = [0.75 0.75 0.95];
      else
        app.bSetReference.BackgroundColor = [0.7 0.7 0.7];
        n = 1;
      end
      app.pmSlice.Items = str;
      app.pmSlice.ItemsData = 0:n;
    end

    %----------------------------------------------------------------------
    function UpdateParameters(app)
      n = app.pmSlice.Value;
      if n == 0, n = 1; end

      app.Parameter{n}.ShiftX = app.eShiftX.Value;
      app.Parameter{n}.ShiftY = app.eShiftY.Value;
      app.Parameter{n}.ZoomX = app.eZoomX.Value;
      app.Parameter{n}.ZoomY = app.eZoomY.Value;
    end
  end


  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, caller, guid)
      app.KazanViewer = caller;
      app.guid = guid;

      app.operator = '-';
      app.Parameter{1} = app.UpdateSet([]);

      app.UpdateSlice();

      hh = [app.pmShiftX, app.pmShiftY, app.pmZoomX, app.pmZoomY];
      set(hh, 'Items', {'10e-7', '10e-6', '10e-5', '10e-4', '0.001', '0.01', '0.1', '1', '10', '100', '1000', '1e4', '1e5', '1e6', '1e7', '1e8'});
      set(hh, 'ItemsData', [10e-7, 10e-6, 10e-5, 10e-4, 0.001, 0.01, 0.1, 1, 10, 100, 1000, 1e4, 1e5, 1e6, 1e7, 1e8]);
    end

    % Value changed function: cbDivide, cbMinus, cbMultiply, 
    % cbPlus, cbSimpleMath
    function OnOperationChanged(app, event)
      hh = [app.cbPlus, app.cbMinus, app.cbDivide, app.cbMultiply, app.cbSimpleMath];
      set(hh, 'Value', 0);
      event.Source.Value = 1;
      app.operator = get(event.Source, 'Text');
      app.PlotThis();
    end

    % Callback function: cbInverseResult, cbShiftResult, 
    % mShowReference, mShowResult, mShowTrace
    function UpdateCheckBox(app, event)
      switch event.Source
        case {app.cbInverseResult, app.cbShiftResult}
          %       event.Source.Value = ~event.Source.Value;
        case {app.mShowReference, app.mShowResult, app.mShowTrace}
          event.Source.Checked = ~event.Source.Checked;
      end
      app.PlotThis();
    end

    % Value changed function: eShiftResult, eShiftX, eShiftY, 
    % eZoomX, eZoomY
    function edit_Callback(app, event)
      if app.pmSlice.Value == 0
        n=1:length(app.Parameter);
      else
        n=app.pmSlice.Value;
      end
      for ii = n
        switch event.Source
          case app.eShiftX, app.Parameter{ii}.ShiftX = app.eShiftX.Value;
          case app.eShiftY, app.Parameter{ii}.ShiftY = app.eShiftY.Value;
          case app.eZoomX, app.Parameter{ii}.ZoomX = app.eZoomX.Value;
          case app.eZoomY, app.Parameter{ii}.ZoomY = app.eZoomY.Value;
        end
      end
      app.PlotThis();
    end

    % Menu selected function: mAbout
    function mAbout_Callback(app, event)
      msgbox({'MINUSBOX3'; 'by Boris Epel and Alexey Silakov, 2003-04'; ''; ...
        'Result = Reference +-*/ CurData(x*zoomx + shiftx)*zoomy+shifty';...
        'or Result = CurData(x*zoomx + shiftx)*zoomy+shifty';
        'If abscissas of the Reference and Current Data are not identical,'; ' ''spline'' procedure is used to interpolate the data';...
        'The Result can be inverted and shifted along Y axis';
        ''; ...
        'IMPORTANT: The Ref. and Cur. Data must have same number of columns'}, ...
        'About', 'help');
    end

    % Button pushed function: bSetReference
    function SetReferencePressed(app, event)
      app.Ref = app.KazanViewer.GetData('source');

      app.UpdateSlice();
      app.PlotThis();
    end

    % Button pushed function: bResetZoom, pbResetShift, pbToRef
    function ButtonSelected(app, event)
      switch event.Source
        case app.bResetZoom
          app.eZoomX.Value = 1;
          app.eZoomY.Value = 1;
          app.UpdateParameters();
        case app.pbResetShift
          app.eShiftX.Value = 1;
          app.eShiftY.Value = 1;
          app.UpdateParameters();
        case app.pbToRef
          if isempty(app.Ref), return; end

          src = app.KazanViewer.GetData('source');
          y  = app.Ref.y;
          y1 = src.y;
          kk = (max(real(y))-min(real(y)))/(max(real(y1))-min(real(y1)));
          zmx = 1;
          zmy = kk;
          yprime = y1*kk;
          shx = 0;
          shy = min(real(y))-min(real(yprime));

          app.eShiftX.Value = shx;
          app.eShiftY.Value = shy;
          app.eZoomX.Value = zmx;
          app.eZoomY.Value = zmy;
          app.UpdateParameters();
      end
      app.PlotThis();
    end

    % Value changed function: pmSlice
    function pmSlice_Callback(app, event)
      n = app.pmSlice.Value;
      if n == 0, n = 1; end

      newset = app.UpdateSet(app.Parameter{n});
      app.eShiftX.Value = newset.ShiftX;
      app.eShiftY.Value = newset.ShiftY;
      app.eZoomX.Value = newset.ZoomX;
      app.eZoomY.Value = newset.ZoomY;

    end

    % Size changed function: figure1
    function figure1SizeChanged(app, event)
      standard_size = [70   669   265   365];
      fig_size = app.figure1.Position;
      scaling_factor = min(fig_size(3:4) ./ standard_size(3:4)) * 1.2;

      if ~isempty(app.KazanViewer)
        Font = app.KazanViewer.Get('font');
        font_size = Font * scaling_factor;
        set(findall(app.figure1, 'Type', 'UIButton'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UIStateButton'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UISpinner'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UILabel'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UICheckBox'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UIEdit'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UIDropDown'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UIListBox'), 'FontSize', font_size);
      end
    end

    % Close request function: figure1
    function figure1CloseRequest(app, event)
      try app.KazanViewer.MakePlugins('close', app); catch; end
      delete(app);
    end

    % Value changed function: pmShiftX, pmShiftY, pmZoomX, pmZoomY
    function OnScaleChanged(app, event)
      switch event.Source
        case app.pmShiftX, app.eShiftX.Step = app.pmShiftX.Value;
        case app.pmShiftY, app.eShiftY.Step = app.pmShiftY.Value; app.eShiftResult.Step = app.pmShiftY.Value;
        case app.pmZoomX, app.eZoomX.Step = app.pmZoomX.Value;
        case app.pmZoomY, app.eZoomY.Step = app.pmZoomY.Value;
      end
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.figure1 = uifigure('Visible', 'off');
      app.figure1.Name = 'Minusbox';
      app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
      app.figure1.SizeChangedFcn = createCallbackFcn(app, @figure1SizeChanged, true);
      app.figure1.Tag = 'figure1';
      app.uimenu1 = uimenu(app.figure1);
      app.uimenu1.Text = 'View';
      app.uimenu1.Tag = 'uimenu1';
      app.mShowReference = uimenu(app.uimenu1);
      app.mShowReference.MenuSelectedFcn = createCallbackFcn(app, @UpdateCheckBox, true);
      app.mShowReference.Checked = 'on';
      app.mShowReference.Text = 'Show Reference';
      app.mShowReference.Tag = 'mShRef';
      app.mShowTrace = uimenu(app.uimenu1);
      app.mShowTrace.MenuSelectedFcn = createCallbackFcn(app, @UpdateCheckBox, true);
      app.mShowTrace.Checked = 'on';
      app.mShowTrace.Text = 'Show Trace 2';
      app.mShowTrace.Tag = 'mShTrace';
      app.mShowResult = uimenu(app.uimenu1);
      app.mShowResult.MenuSelectedFcn = createCallbackFcn(app, @UpdateCheckBox, true);
      app.mShowResult.Checked = 'on';
      app.mShowResult.Text = 'Show Result';
      app.mShowResult.Tag = 'mShRes';
      app.mAbout = uimenu(app.figure1);
      app.mAbout.MenuSelectedFcn = createCallbackFcn(app, @mAbout_Callback, true);
      app.mAbout.Text = 'About';
      app.mAbout.Tag = 'mAbout';
      app.GridLayout = uigridlayout(app.figure1);
      app.bSetReference = uibutton(app.GridLayout, 'push');
      app.bSetReference.ButtonPushedFcn = createCallbackFcn(app, @SetReferencePressed, true);
      app.bSetReference.Tag = 'pbRef';
      app.bSetReference.Text = 'Set as reference';
      app.cbPlus = uicheckbox(app.GridLayout);
      app.cbPlus.ValueChangedFcn = createCallbackFcn(app, @OnOperationChanged, true);
      app.cbPlus.Tag = 'chkPluse';
      app.cbPlus.Text = '+';
      app.cbMinus = uicheckbox(app.GridLayout);
      app.cbMinus.ValueChangedFcn = createCallbackFcn(app, @OnOperationChanged, true);
      app.cbMinus.Tag = 'chkMinus';
      app.cbMinus.Text = '-';
      app.cbMinus.Value = true;
      app.cbMultiply = uicheckbox(app.GridLayout);
      app.cbMultiply.ValueChangedFcn = createCallbackFcn(app, @OnOperationChanged, true);
      app.cbMultiply.Tag = 'chkMult';
      app.cbMultiply.Text = '.*';
      app.cbDivide = uicheckbox(app.GridLayout);
      app.cbDivide.ValueChangedFcn = createCallbackFcn(app, @OnOperationChanged, true);
      app.cbDivide.Tag = 'chkDiff';
      app.cbDivide.Text = './';
      app.cbSimpleMath = uicheckbox(app.GridLayout);
      app.cbSimpleMath.ValueChangedFcn = createCallbackFcn(app, @OnOperationChanged, true);
      app.cbSimpleMath.Tag = 'chkRef';
      app.cbSimpleMath.Text = 'Res = Zoom*Src+Shift';
      app.text1 = uilabel(app.GridLayout);
      app.text1.Tag = 'text1';
      app.text1.Text = 'Shift X';
      app.pmShiftX = uidropdown(app.GridLayout);
      app.pmShiftX.Items = {'1e-7', '1e-6', '1e-5', '1e-4', '0.001', '0.01', '0.1', '1', '10', '100', '1000', '1e4', '1e5', '1e6', '1e7', '1e8'};
      app.pmShiftX.ValueChangedFcn = createCallbackFcn(app, @OnScaleChanged, true);
      app.pmShiftX.Tag = 'pmShiftX';
      app.pmShiftX.Value = '0.1';
      app.pmShiftY = uidropdown(app.GridLayout);
      app.pmShiftY.Items = {'1e-7', '1e-6', '1e-5', '1e-4', '0.001', '0.01', '0.1', '1', '10', '100', '1000', '1e4', '1e5', '1e6', '1e7'};
      app.pmShiftY.ValueChangedFcn = createCallbackFcn(app, @OnScaleChanged, true);
      app.pmShiftY.Tag = 'pmShiftY';
      app.pmShiftY.Value = '0.1';
      app.text4 = uilabel(app.GridLayout);
      app.text4.Tag = 'text4';
      app.text4.Text = 'Shift Y';
      app.pbResetShift = uibutton(app.GridLayout, 'push');
      app.pbResetShift.ButtonPushedFcn = createCallbackFcn(app, @ButtonSelected, true);
      app.pbResetShift.Tag = 'pbShZero';
      app.pbResetShift.Text = 'Reset';
      app.pbToRef = uibutton(app.GridLayout, 'push');
      app.pbToRef.ButtonPushedFcn = createCallbackFcn(app, @ButtonSelected, true);
      app.pbToRef.Tag = 'pbToRef';
      app.pbToRef.Text = '-> Ref.';
      app.text2 = uilabel(app.GridLayout);
      app.text2.Tag = 'text2';
      app.text2.Text = 'Zoom X';
      app.text3 = uilabel(app.GridLayout);
      app.text3.Tag = 'text3';
      app.text3.Text = 'Zoom Y';
      app.pmZoomX = uidropdown(app.GridLayout);
      app.pmZoomX.Items = {'10e-6', '10e-5', '10e-4', '0.001', '0.01', '0.1', '1', '10', '100', '1000', '1e4', '1e5', '1e6'};
      app.pmZoomX.ValueChangedFcn = createCallbackFcn(app, @OnScaleChanged, true);
      app.pmZoomX.Tag = 'pmZoomX';
      app.pmZoomX.Value = '1';
      app.pmZoomY = uidropdown(app.GridLayout);
      app.pmZoomY.Items = {'10e-6', '10e-5', '10e-4', '0.001', '0.01', '0.1', '1', '10', '100', '1000', '1e4', '1e5', '1e6'};
      app.pmZoomY.ValueChangedFcn = createCallbackFcn(app, @OnScaleChanged, true);
      app.pmZoomY.Tag = 'pmZoomY';
      app.pmZoomY.Value = '1';
      app.bResetZoom = uibutton(app.GridLayout, 'push');
      app.bResetZoom.ButtonPushedFcn = createCallbackFcn(app, @ButtonSelected, true);
      app.bResetZoom.Tag = 'pbZmZero';
      app.bResetZoom.Text = 'Reset';
      app.pmSlice = uidropdown(app.GridLayout);
      app.pmSlice.Items = {'slice'};
      app.pmSlice.ValueChangedFcn = createCallbackFcn(app, @pmSlice_Callback, true);
      app.pmSlice.Tag = 'pmSlice';
      app.pmSlice.Value = 'slice';
      app.text6 = uilabel(app.GridLayout);
      app.text6.Tag = 'text6';
      app.text6.Text = 'slice';
      app.cbShiftResult = uicheckbox(app.GridLayout);
      app.cbShiftResult.ValueChangedFcn = createCallbackFcn(app, @UpdateCheckBox, true);
      app.cbShiftResult.Tag = 'chkSftRes';
      app.cbShiftResult.Text = 'Shift result';
      app.cbInverseResult = uicheckbox(app.GridLayout);
      app.cbInverseResult.ValueChangedFcn = createCallbackFcn(app, @UpdateCheckBox, true);
      app.cbInverseResult.Tag = 'chkRevRes';
      app.cbInverseResult.Text = 'Inverse result';
      app.eShiftX = uispinner(app.GridLayout);
      app.eShiftX.ValueChangedFcn = createCallbackFcn(app, @edit_Callback, true);
      app.eShiftY = uispinner(app.GridLayout);
      app.eShiftY.ValueChangedFcn = createCallbackFcn(app, @edit_Callback, true);
      app.eZoomX = uispinner(app.GridLayout);
      app.eZoomX.ValueChangedFcn = createCallbackFcn(app, @edit_Callback, true);
      app.eZoomX.Value = 1;
      app.eZoomY = uispinner(app.GridLayout);
      app.eZoomY.ValueChangedFcn = createCallbackFcn(app, @edit_Callback, true);
      app.eZoomY.Value = 1;
      app.eShiftResult = uispinner(app.GridLayout);
      app.eShiftResult.ValueChangedFcn = createCallbackFcn(app, @edit_Callback, true);
      % Show the figure after all components are created
      app.figure1.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = MinusBox3(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.figure1)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.figure1)
    end
  end
end