classdef mathplugin3 < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    figure1              matlab.ui.Figure
    GridLayout           matlab.ui.container.GridLayout
    pbPhaseStep1         matlab.ui.control.StateButton
    bResetCut            matlab.ui.control.Button
    bAbs                 matlab.ui.control.StateButton
    bImag                matlab.ui.control.StateButton
    bReal                matlab.ui.control.StateButton
    bAsIs                matlab.ui.control.StateButton
    bCutData             matlab.ui.control.StateButton
    e2DLast              matlab.ui.control.Spinner
    endSpinner_2Label    matlab.ui.control.Label
    e2DFirst             matlab.ui.control.Spinner
    startSpinner_2Label  matlab.ui.control.Label
    e1DLast              matlab.ui.control.Spinner
    endSpinnerLabel      matlab.ui.control.Label
    e1DFirst             matlab.ui.control.Spinner
    startSpinnerLabel    matlab.ui.control.Label
    eCut2Dim             matlab.ui.control.EditField
    cbExpression2Dim     matlab.ui.control.CheckBox
    ePhase1sh            matlab.ui.control.Spinner
    dtLabel              matlab.ui.control.Label
    ePhase1Pivot         matlab.ui.control.Spinner
    pivotLabel           matlab.ui.control.Label
    ePhase1              matlab.ui.control.Spinner
    orderLabel_2         matlab.ui.control.Label
    ePhase               matlab.ui.control.Spinner
    orderLabel           matlab.ui.control.Label
    pb10                 matlab.ui.control.StateButton
    pb0p1                matlab.ui.control.StateButton
    pbPhase              matlab.ui.control.StateButton
    text13               matlab.ui.control.Label
    text12               matlab.ui.control.Label
    tbSum                matlab.ui.control.StateButton
    pbTranspose          matlab.ui.control.StateButton
  end


  properties (Access = private)
    KazanViewer % Description
  end

  properties (Access = public)
    guid % Description
  end

  %----------------------------------------------------------------------
  methods (Access = private)

    %----------------------------------------------------------------------
    function Plott(app)
      src = app.KazanViewer.GetData('source');

      if app.bReal.Value
        src.y = real(src.y);
      elseif app.bImag.Value
        src.y = imag(src.y);
      elseif app.bAbs.Value
        src.y = abs(src.y);
      end

      sz = size(src.y);
      if app.bCutData.Value
        d1f = app.e1DFirst.Value;
        d1l = app.e1DLast.Value;
        if d1f < 1 || d1l < 1, d1f = 1; d1l = sz(1); end
        d2f = app.e2DFirst.Value;
        d2l = app.e2DLast.Value;
        if d2f < 1 || d2l < 1, d2f = 1; d2l = sz(2); end
        d1f = min(d1f, sz(1));
        d1l = min(d1l, sz(1));
        d2f = min(d2f, sz(2));
        d2l = min(d2l, sz(2));


        if sz(2)>1 && app.cbExpression2Dim.Value
          idx = eval(app.eCut2Dim.Value);
          src.y = src.y(d1f:d1l, idx);
          src.ax.x = src.ax.x(d1f:d1l);
          src.ax.y = src.ax.y(idx);
        else
          src.y = src.y(d1f:d1l, d2f:d2l);
          src.ax.x = src.ax.x(d1f:d1l);
          src.ax.y = src.ax.y(d2f:d2l);
        end
      end

      if app.pbTranspose.Value
        src.y = src.y.';
        ax = src.ax;
        src.ax.x = ax.y;
        if isfield(ax, 'ylabel')
          src.ax.xlabel = ax.ylabel;
        end
        src.ax.y = ax.x;
        if isfield(ax, 'xlabel')
          src.ax.ylabel = ax.xlabel;
        end
      end

      if app.tbSum.Value
        src.y = sum(src.y,2);
        src.ax.y = 1;
      end

      [sz1,sz2] = size(src.y);
      if app.pbPhase.Value
        phase = app.ePhase.Value;
        phase1 = app.ePhase1.Value;
        phase1piv = app.ePhase1Pivot.Value;
        phase1sh = app.ePhase1sh.Value;
        for jj=1:sz2
          src.y(:,jj) = src.y(:,jj).*exp(-1i*...
            (phase/180*pi+(phase1+phase1sh*(jj-1))*pi/180*([1:sz1]'-phase1piv)));
        end
      end

      out{1} = src;
      app.KazanViewer.SetData('output', out);
    end

    %----------------------------------------------------------------------

    % Update components that require runtime configuration
  end

  methods (Access = public)
    function FileUpdate(app)

      src = app.KazanViewer.GetData('source');
      sz = size(src.y);

      if sz(1) > 1
        app.e1DFirst.Limits = [1,sz(1)];
        app.e1DLast.Limits = [1,sz(1)];
        app.e1DFirst.Enable = 1;
        app.e1DLast.Enable = 1;
      else
        app.e1DFirst.Enable = 0;
        app.e1DLast.Enable = 0;
      end
      if sz(2) > 1
        app.e2DFirst.Limits = [1,sz(2)];
        app.e2DLast.Limits = [1,sz(2)];
        app.e2DFirst.Enable = 1;
        app.e2DLast.Enable = 1;
      else
        app.e2DFirst.Enable = 0;
        app.e2DLast.Enable = 0;
      end
    end
  end

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, caller, guid)
      app.KazanViewer = caller;
      app.guid = guid;

      app.FileUpdate();
    end

    % Close request function: figure1
    function figure1CloseRequest(app, event)
      try app.KazanViewer.MakePlugins('close', app); catch; end
      delete(app)
    end

    % Size changed function: figure1
    function figure1SizeChanged(app, event)
      standard_size = [70   669   315   465];
      fig_size = app.figure1.Position;
      scaling_factor = min(fig_size(3:4) ./ standard_size(3:4)) * 1.2;

      if ~isempty(app.KazanViewer)
        Font = app.KazanViewer.Get('font');
        font_size = Font * scaling_factor;
        set(findall(app.figure1, 'Type', 'UIButton'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UIStateButton'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UISpinner'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UILabel'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UICheckBox'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UIEdit'), 'FontSize', font_size);
      end

    end

    % Value changed function: bCutData, cbExpression2Dim, e1DFirst, 
    % e1DLast, e2DFirst, e2DLast, ePhase, ePhase1, ePhase1Pivot, 
    % ePhase1sh, pbPhase, pbTranspose, tbSum
    function UpdatePlot(app, event)
      app.Plott();
    end

    % Callback function: bAbs, bAsIs, bImag, bReal, bResetCut, pb0p1, 
    % pb10, pbPhaseStep1
    function UpdateDataProcessing(app, event)
      switch event.Source
        case {app.bAsIs, app.bReal, app.bImag, app.bAbs}
          idx = [app.bAsIs, app.bReal, app.bImag, app.bAbs];
          set(idx, 'Value', 0)
          event.Source.Value = 1;
        case app.bResetCut

          src = app.KazanViewer.GetData('source');
          sz = size(src.y);

          app.e1DFirst.Value = 1;
          app.e1DLast.Value = sz(1);
          if sz(2) > 1
            app.e2DFirst.Value = 1;
            app.e2DLast.Value = sz(2);
          end
        case {app.pb0p1,app.pbPhaseStep1, app.pb10}
          idx = [app.pb0p1, app.pbPhaseStep1, app.pb10];
          steps = [0.1, 1.0, 10.0];
          step = steps(idx == event.Source);
          set(idx, 'Value', 0)
          app.ePhase.Step = step;
          app.ePhase1.Step = step;
          event.Source.Value = 1;
      end
      app.Plott();
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.figure1 = uifigure('Visible', 'off');
      app.figure1.Name = 'mathplugin';
      app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
      app.figure1.SizeChangedFcn = createCallbackFcn(app, @figure1SizeChanged, true);
      app.figure1.Tag = 'figure1';
      app.GridLayout = uigridlayout(app.figure1);
      app.pbTranspose = uibutton(app.GridLayout, 'state');
      app.pbTranspose.ValueChangedFcn = createCallbackFcn(app, @UpdatePlot, true);
      app.pbTranspose.Tag = 'pbTranspose';
      app.pbTranspose.Text = 'Transpose';
      app.tbSum = uibutton(app.GridLayout, 'state');
      app.tbSum.ValueChangedFcn = createCallbackFcn(app, @UpdatePlot, true);
      app.tbSum.Tag = 'tbSum';
      app.tbSum.Text = 'sum(y,2)';
      app.text12 = uilabel(app.GridLayout);
      app.text12.Tag = 'text12';
      app.text12.Text = '1';
      app.text13 = uilabel(app.GridLayout);
      app.text13.Tag = 'text13';
      app.text13.Text = '2';
      app.pbPhase = uibutton(app.GridLayout, 'state');
      app.pbPhase.ValueChangedFcn = createCallbackFcn(app, @UpdatePlot, true);
      app.pbPhase.Tag = 'pbPhase';
      app.pbPhase.Text = 'Phase';
      app.pb0p1 = uibutton(app.GridLayout, 'state');
      app.pb0p1.ValueChangedFcn = createCallbackFcn(app, @UpdateDataProcessing, true);
      app.pb0p1.Tag = 'pb01';
      app.pb0p1.Text = 'x0.1';
      app.pb10 = uibutton(app.GridLayout, 'state');
      app.pb10.ValueChangedFcn = createCallbackFcn(app, @UpdateDataProcessing, true);
      app.pb10.Tag = 'pb10';
      app.pb10.Text = 'x10';
      app.orderLabel = uilabel(app.GridLayout);
      app.orderLabel.Text = {'0 order'; ''};
      app.ePhase = uispinner(app.GridLayout);
      app.ePhase.Limits = [-360 360];
      app.ePhase.ValueChangedFcn = createCallbackFcn(app, @UpdatePlot, true);
      app.orderLabel_2 = uilabel(app.GridLayout);
      app.orderLabel_2.Text = '1 order';
      app.ePhase1 = uispinner(app.GridLayout);
      app.ePhase1.Limits = [-360 360];
      app.ePhase1.ValueChangedFcn = createCallbackFcn(app, @UpdatePlot, true);
      app.pivotLabel = uilabel(app.GridLayout);
      app.pivotLabel.Text = {'pivot'; ''};
      app.ePhase1Pivot = uispinner(app.GridLayout);
      app.ePhase1Pivot.ValueChangedFcn = createCallbackFcn(app, @UpdatePlot, true);
      app.dtLabel = uilabel(app.GridLayout);
      app.dtLabel.Text = {'dt'; ''};
      app.ePhase1sh = uispinner(app.GridLayout);
      app.ePhase1sh.ValueChangedFcn = createCallbackFcn(app, @UpdatePlot, true);
      app.cbExpression2Dim = uicheckbox(app.GridLayout);
      app.cbExpression2Dim.ValueChangedFcn = createCallbackFcn(app, @UpdatePlot, true);
      app.cbExpression2Dim.Tag = 'cbExpression2Dim';
      app.cbExpression2Dim.Tooltip = 'Expression (i.e. [5,8,9])';
      app.cbExpression2Dim.Text = ' eval';
      app.eCut2Dim = uieditfield(app.GridLayout, 'text');
      app.eCut2Dim.Tag = 'eCut2Dim';
      app.eCut2Dim.Tooltip = {'Expression (i.e. [5,8,9])'};
      app.startSpinnerLabel = uilabel(app.GridLayout);
      app.startSpinnerLabel.Text = 'start';
      app.e1DFirst = uispinner(app.GridLayout);
      app.e1DFirst.ValueDisplayFormat = '%i';
      app.e1DFirst.ValueChangedFcn = createCallbackFcn(app, @UpdatePlot, true);
      app.endSpinnerLabel = uilabel(app.GridLayout);
      app.endSpinnerLabel.Text = 'end';
      app.e1DLast = uispinner(app.GridLayout);
      app.e1DLast.ValueDisplayFormat = '%i';
      app.e1DLast.ValueChangedFcn = createCallbackFcn(app, @UpdatePlot, true);
      app.startSpinner_2Label = uilabel(app.GridLayout);
      app.startSpinner_2Label.Text = 'start';
      app.e2DFirst = uispinner(app.GridLayout);
      app.e2DFirst.ValueDisplayFormat = '%i';
      app.e2DFirst.ValueChangedFcn = createCallbackFcn(app, @UpdatePlot, true);
      app.endSpinner_2Label = uilabel(app.GridLayout);
      app.endSpinner_2Label.Text = 'end';
      app.e2DLast = uispinner(app.GridLayout);
      app.e2DLast.ValueDisplayFormat = '%i';
      app.e2DLast.ValueChangedFcn = createCallbackFcn(app, @UpdatePlot, true);
      app.bCutData = uibutton(app.GridLayout, 'state');
      app.bCutData.ValueChangedFcn = createCallbackFcn(app, @UpdatePlot, true);
      app.bCutData.Text = 'Cut Data';
      app.bAsIs = uibutton(app.GridLayout, 'state');
      app.bAsIs.ValueChangedFcn = createCallbackFcn(app, @UpdateDataProcessing, true);
      app.bAsIs.Text = 'as is';
      app.bAsIs.Value = true;
      app.bReal = uibutton(app.GridLayout, 'state');
      app.bReal.ValueChangedFcn = createCallbackFcn(app, @UpdateDataProcessing, true);
      app.bReal.Text = 'real';
      app.bImag = uibutton(app.GridLayout, 'state');
      app.bImag.ValueChangedFcn = createCallbackFcn(app, @UpdateDataProcessing, true);
      app.bImag.Text = 'imag';
      app.bAbs = uibutton(app.GridLayout, 'state');
      app.bAbs.ValueChangedFcn = createCallbackFcn(app, @UpdateDataProcessing, true);
      app.bAbs.Text = 'abs';
      app.bResetCut = uibutton(app.GridLayout, 'push');
      app.bResetCut.ButtonPushedFcn = createCallbackFcn(app, @UpdateDataProcessing, true);
      app.bResetCut.Text = 'Reset';
      app.pbPhaseStep1 = uibutton(app.GridLayout, 'state');
      app.pbPhaseStep1.ValueChangedFcn = createCallbackFcn(app, @UpdateDataProcessing, true);
      app.pbPhaseStep1.Text = 'x1';
      app.pbPhaseStep1.Value = true;
      % Show the figure after all components are created
      app.figure1.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = mathplugin3(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.figure1)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.figure1)
    end
  end
end