classdef SNRFIDcheckAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                     matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        pushbutton1                 matlab.ui.control.Button
        SNRstat                     matlab.ui.control.Label
        FFTStat                     matlab.ui.control.Label
        OtherStat                   matlab.ui.control.Label
        GridLayout2                 matlab.ui.container.GridLayout
        BlockpointsptsLabel         matlab.ui.control.Label
        eBlock                      matlab.ui.control.NumericEditField
        FirstbaselineptsLabel       matlab.ui.control.Label
        eBaseline                   matlab.ui.control.NumericEditField
        ZeroTimensLabel             matlab.ui.control.Label
        eZeroTime                   matlab.ui.control.NumericEditField
        eBW                         matlab.ui.control.NumericEditField
        BandwidthMHzEditFieldLabel  matlab.ui.control.Label
        FieldRangeGLabel            matlab.ui.control.Label
        eFieldRange                 matlab.ui.control.NumericEditField
        cbExtrapolateto0            matlab.ui.control.CheckBox
        ExtrapolatetoZeroLabel      matlab.ui.control.Label
        cbSubtractBaseline          matlab.ui.control.CheckBox
        SubtractbaselineLabel       matlab.ui.control.Label
        GridLayout3                 matlab.ui.container.GridLayout
        NoiseAxis                   matlab.ui.control.UIAxes
        TimeAxis                    matlab.ui.control.UIAxes
        FFTaxis                     matlab.ui.control.UIAxes
    end


  properties (Access = private)
    LastPath = '' % Last file opened
    Data          % Loaded data
    nSlices = 0   % Number of slices
    Shots = []
    Signal = 0
    idxSignal = 1
    Ref = []
    BL  = []
    CF = 0 % Description
  end

  methods (Access = private)
    function DrawIt(app)
      
      % Time domain
      Toff = app.eZeroTime.Value * 1e-3;
      T = app.Data.ax.x*1e6 + Toff;
      block_idx = app.eBlock.Value;
      block_idx = min(max(1, block_idx), size(app.Data.ax.x,1));
      dt = mean(diff(app.Data.ax.x));
      FTaxMax = 1/2/dt * 1E-6;
      BW = app.eBW.Value;
      FieldRange = app.eFieldRange.Value;

      cla(app.TimeAxis)
      rawdata = app.Data.y;
      if BW <= 500
        %               n = 8096;
        %               rawdata(n) = 0;
        %               FT = fftshift(fft(rawdata));
        %               FTax = linspace(-FTaxMax, FTaxMax, length(FT));
        %               idx = abs(FTax) > BW/2;
        %               FT(idx) = 0;
        %               rawdata = ifft(fftshift(FT));
        %               rawdata = rawdata(1:length(app.Data.y));
      end
      plot(app.TimeAxis, T, real(rawdata)); hold(app.TimeAxis, 'on');
      plot(app.TimeAxis, T, imag(rawdata));
      xlabel(app.TimeAxis, 'Time [us]');
      grid(app.TimeAxis, 'on')
      axis(app.TimeAxis, [-Inf, Inf, -0.1*max(real(app.Data.y(block_idx:end))), Inf])

      % SNR calculation
      yy = real(app.Data.y);
      yy(1:block_idx, :) = 0;
      [app.Signal, app.idxSignal] = max(yy);
      plot(app.TimeAxis, T(app.idxSignal) * [1,1], [0, real(app.Data.y(app.idxSignal))], '-k*')
      plot(app.TimeAxis, T(block_idx) * [1,1], real(app.Data.y(app.idxSignal)) * [0.75, 1], ':k')
      BLidx = app.eBaseline.Value;

      noise_sampling = int32(linspace(BLidx, length(T), 6));
      noise_sampling = noise_sampling(1:end-2);
      rmse = @(x) sqrt(mean(x.^2));
      for ii=1:length(noise_sampling)
        noise_trace = app.Data.y(noise_sampling(ii):end);
        % Filtering
        noise_trace = noise_trace - mean(noise_trace);
        if BW <= 500
          FT = fftshift(fft(noise_trace));
          FTax = linspace(-FTaxMax, FTaxMax, length(FT));
          idx = abs(FTax) > BW/2;
          FTFilt = FT; FTFilt(idx) = 0;
          noise_traceFT = ifft(fftshift(FTFilt));
%           figure; subplot(2,1,1);plot(FTax, real(FT), FTax, imag(FT), FTax, real(FTFilt), FTax, imag(FTFilt))
%           subplot(2,1,2); plot(FTax, real(noise_trace), FTax, imag(noise_trace), FTax, real(noise_traceFT), FTax, imag(noise_traceFT))
          noise_trace = noise_traceFT;
        end
        noise_values = noise_trace - mean(noise_trace);
%         noise(ii) = std(noise_values);
        noise(ii) = rmse([real(noise_values);imag(noise_values)]);
      end

      TT  = T(block_idx+1:end);
      yyy = yy(block_idx+1:end);
      EEE = @(x) x(1) .* exp(-x(2)*TT) + x(3);
      EEE2 = @(x) sqrt(sum((EEE(x) - yyy).^2));
      options = optimset('MaxFunEvals', 60000, 'MaxIter', 30000);
      x = fminsearch(EEE2, [3*app.Signal, 20/max(TT), 0.0], options);
      FIT = EEE(x);
      idxFIT = FIT < app.Signal;
      plot(app.TimeAxis, TT(idxFIT), FIT(idxFIT), 'g')
      T2exp = 1/x(2);

      Tmeas = T(app.idxSignal);  % where signal is measured

      % extrapolation of signal
      if app.cbExtrapolateto0.Value == 1
        app.Signal = x(1);
        Tmeas = 0.0;
      end

      SNR = app.Signal./noise;

      plot(app.TimeAxis, T(noise_sampling), SNR / max(SNR) * app.Signal / 4, '-k*')
      for ii=1:length(SNR)
        text(app.TimeAxis, T(noise_sampling(ii)), SNR(ii) / max(SNR) * app.Signal / 3.7, sprintf('%5.2f', SNR(ii)), 'Rotation',90);
      end

      app.SNRstat.Text = sprintf('SNR = %5.2f (%7.3f@%5.3fus/%7.4f)\nDT = %5.3fus  T2* = %5.3fus (%5.1fmG)', SNR(1), app.Signal, Tmeas, noise(1), T(block_idx), T2exp, 1/T2exp/2.802/pi*1e3);
      
      % additional code
      try
        %             figure(1); clf; plot(TT, yyy - EEE(x)); hold on
        %             XX = linspace(0, max(TT)/2,1000);
        %             plot(XX, 0.1*cos(XX * 2*pi*1.2*2.8) + 0.06*cos(XX * 2*pi*0.625*2.8))
        %             grid on
      catch err
      end

      % Linewidth
      n = 32384;
      cla(app.FFTaxis)
      y = app.Data.y(block_idx:end);
      yyy = y; 
      if app.cbSubtractBaseline.Value == 1
        yyy(length(y):n) = y(end); % zero filling
        yyy = yyy - y(end);
      else
        yyy(length(y):n) = 0.0; % zero filling
      end
      FT = fftshift(fft(yyy));
      FTax = linspace(-FTaxMax, FTaxMax, length(yyy));

      idx = abs(FTax) < FieldRange/2;
      HF = [-1.25, -0.66, 0.66, 1.25];
      HFH = max(real(FT)) / 15;
      plot(app.FFTaxis, FTax(idx), real(FT(idx))); hold(app.FFTaxis, 'on');
      plot(app.FFTaxis, FTax(idx), imag(FT(idx)));
      plot(app.FFTaxis, FTax(idx), abs(FT(idx)));
      for ii=1:length(HF)
        plot(app.FFTaxis, HF(ii)*[1,1], [0,HFH])
      end
      xlabel(app.FFTaxis, 'Field [G]');
      grid(app.FFTaxis, 'on')
      axis(app.FFTaxis, 'tight')

      HH = max(real(FT)) / 2.0;
      P = real(FT) >= HH;
      HHA = max(abs(FT)) / 2.0;
      PA = abs(FT) >= HHA;
      app.FFTStat.Text = sprintf('FWHH = %4.1f mG / %4.1f mG', numel(find(P)) * 2*FTaxMax / n / 2.802e-3, numel(find(PA)) * 2*FTaxMax / n / 2.802e-3);

      ma1 = min(FTax(P));
      ma2 = max(FTax(P));
      plot(app.FFTaxis, [ma1,ma2], [1,1] * HH, '-k*')
     
      % noise analysis
      n = 4096;
      cla(app.NoiseAxis); hold(app.NoiseAxis, 'on');
      yyy = app.Data.y(BLidx:end); yyy(n) = 0.0;
      FT = fftshift(fft(yyy));
      FTax = linspace(-FTaxMax, FTaxMax, length(yyy));
      idx = abs(FTax) < BW;
      plot(app.NoiseAxis, FTax(idx)+app.CF, abs(FT(idx)));
      Zidx = abs(FTax) > BW/2;
      FT(Zidx) = 0;
      plot(app.NoiseAxis, FTax(idx)+app.CF, abs(FT(idx)));
      xlabel(app.NoiseAxis, 'Frequency [MHz]');
      grid(app.NoiseAxis, 'on')
      axis(app.NoiseAxis, 'tight')
    end

    function res = GetFrequency(app, dsc)
      res = 0.0;
      % TDMS style
      devices_BRIDGE = safeget(dsc, 'devices_BRIDGE', []);
      if ~isempty(devices_BRIDGE)
        FrequencyString = safeget(devices_BRIDGE, 'Frequency', '');
        if ~isempty(FrequencyString)
          res = kvgetvalue(FrequencyString) * 1e-6; % MHz
        end
      else 
        % D01 style
        FrequencyString = safeget(dsc, 'SRC1_Frequency', []);
        if ~isempty(FrequencyString)
          res = kvgetvalue(FrequencyString) * 1e-6; % MHz
        end
      end
    end

    function results = GetShots(app, dsc)
      results = 1;
      try
        % TDMS data structure
        str = dsc.axis_transient;
        res = sscanf(str, "%c,%i,%i,%c,%c");
        results = res(3) * 4; % four phase cycles 
      catch
        % D01 data structure
        str = dsc.sweep_transient;
        res = sscanf(str, "%c,%i,%i,%c,%c");
        results = res(3) * 4; % four phase cycles
      end
    end
  end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function SNRcheckGUI_OpeningFcn(app, varargin)
            
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
      [fname, pathname] = uigetfile({'*.exp;*.tdms;*.mat'}, 'Select a file', app.LastPath);
      if ~isequal(fname,0) && ~isequal(pathname,0)
        app.LastPath = pathname;
        filename = fullfile(pathname, fname);
        set(app.figure1, 'Name', ['SNRFIDcheckAPP: ', filename])

        [~,~,ext]=fileparts(filename);

        if contains(ext, 'exp')
          [app.Data.ax, app.Data.y, app.Data.dsc] = kv_d01read(filename);
        elseif contains(ext, 'tdms')
          [app.Data.ax, app.Data.y, app.Data.dsc] = kv_smtdmsread(filename);
        elseif contains(ext, 'mat')
          A = load(filename);
          app.Data.y = A.projs;
          app.Data.ax.x = A.dwellTp * 1e-9 * (1:length(app.Data.y))';
          app.Data.dsc = [];
          app.Data.dsc.sweep_transient = sprintf('T,2,%i,X,X', A.Tot_Ave/4);
          app.Data.dsc.SRC1_Frequency = sprintf('%6.2fMHz', A.frequency);
        end

        app.nSlices = size(app.Data.y, 2);
        if app.nSlices > 1
          app.Data.y = app.Data.y(:,1);
        end

        app.Shots = GetShots(app, app.Data.dsc);
        app.CF = GetFrequency(app, app.Data.dsc);

        app.OtherStat.Text = sprintf('CF=%5.3f MHz\nShots=%i (overall)', app.CF, app.Shots);

        DrawIt(app);
      end
        end

        % Callback function
        function slider1_Callback(app, event)
      app.slider1.Value = round(app.slider1.Value); % get then round
      DrawIt(app);
        end

        % Value changed function: cbExtrapolateto0, 
        % cbSubtractBaseline, eBW, eBaseline, eBlock, eFieldRange, 
        % eZeroTime
        function eBaselineValueChanged(app, event)
      DrawIt(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'SNRcheckGUI';
            app.GridLayout = uigridlayout(app.figure1);
            app.pushbutton1 = uibutton(app.GridLayout, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Text = 'Load Data';
            app.SNRstat = uilabel(app.GridLayout);
            app.SNRstat.Text = '>';
            app.FFTStat = uilabel(app.GridLayout);
            app.FFTStat.Text = '>';
            app.OtherStat = uilabel(app.GridLayout);
            app.OtherStat.Text = '>';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.BlockpointsptsLabel = uilabel(app.GridLayout2);
            app.BlockpointsptsLabel.Text = 'Block points [pts]';
            app.eBlock = uieditfield(app.GridLayout2, 'numeric');
            app.eBlock.Limits = [0 Inf];
            app.eBlock.ValueDisplayFormat = '%6i';
            app.eBlock.ValueChangedFcn = createCallbackFcn(app, @eBaselineValueChanged, true);
            app.eBlock.Value = 25;
            app.FirstbaselineptsLabel = uilabel(app.GridLayout2);
            app.FirstbaselineptsLabel.Text = 'First baseline [pts]';
            app.eBaseline = uieditfield(app.GridLayout2, 'numeric');
            app.eBaseline.Limits = [1 Inf];
            app.eBaseline.ValueDisplayFormat = '%6i';
            app.eBaseline.ValueChangedFcn = createCallbackFcn(app, @eBaselineValueChanged, true);
            app.eBaseline.Value = 700;
            app.ZeroTimensLabel = uilabel(app.GridLayout2);
            app.ZeroTimensLabel.Text = 'Zero Time [ns]';
            app.eZeroTime = uieditfield(app.GridLayout2, 'numeric');
            app.eZeroTime.Limits = [-1000 1000];
            app.eZeroTime.ValueDisplayFormat = '%6i';
            app.eZeroTime.ValueChangedFcn = createCallbackFcn(app, @eBaselineValueChanged, true);
            app.eZeroTime.Value = 316;
            app.eBW = uieditfield(app.GridLayout2, 'numeric');
            app.eBW.Limits = [0 1000];
            app.eBW.ValueChangedFcn = createCallbackFcn(app, @eBaselineValueChanged, true);
            app.eBW.Value = 50;
            app.BandwidthMHzEditFieldLabel = uilabel(app.GridLayout2);
            app.BandwidthMHzEditFieldLabel.Text = 'Bandwidth [MHz]';
            app.FieldRangeGLabel = uilabel(app.GridLayout2);
            app.FieldRangeGLabel.Text = 'Field Range [G]';
            app.eFieldRange = uieditfield(app.GridLayout2, 'numeric');
            app.eFieldRange.Limits = [0 25];
            app.eFieldRange.ValueChangedFcn = createCallbackFcn(app, @eBaselineValueChanged, true);
            app.eFieldRange.Value = 4;
            app.cbExtrapolateto0 = uicheckbox(app.GridLayout2);
            app.cbExtrapolateto0.ValueChangedFcn = createCallbackFcn(app, @eBaselineValueChanged, true);
            app.cbExtrapolateto0.Text = '';
            app.cbExtrapolateto0.Value = true;
            app.ExtrapolatetoZeroLabel = uilabel(app.GridLayout2);
            app.ExtrapolatetoZeroLabel.Text = 'Extrapolate to Zero';
            app.cbSubtractBaseline = uicheckbox(app.GridLayout2);
            app.cbSubtractBaseline.ValueChangedFcn = createCallbackFcn(app, @eBaselineValueChanged, true);
            app.cbSubtractBaseline.Text = '';
            app.SubtractbaselineLabel = uilabel(app.GridLayout2);
            app.SubtractbaselineLabel.Text = 'Subtract baseline';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.NoiseAxis = uiaxes(app.GridLayout);
            title(app.NoiseAxis, 'Noise spectrum')
            xlabel(app.NoiseAxis, 'X')
            ylabel(app.NoiseAxis, 'Y')
            zlabel(app.NoiseAxis, 'Z')
            app.TimeAxis = uiaxes(app.GridLayout);
            title(app.TimeAxis, 'Time Domain')
            app.FFTaxis = uiaxes(app.GridLayout);
            title(app.FFTaxis, 'Field domain')
            xlabel(app.FFTaxis, 'X')
            ylabel(app.FFTaxis, 'Y')
            zlabel(app.FFTaxis, 'Z')
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SNRFIDcheckAPP(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)SNRcheckGUI_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end