classdef SNRcheckAPP < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    figure1           matlab.ui.Figure
    GridLayout        matlab.ui.container.GridLayout
    pushbutton1       matlab.ui.control.Button
    text2             matlab.ui.control.Label
    slider1           matlab.ui.control.Slider
    GridLayout2       matlab.ui.container.GridLayout
    BlockpointsLabel  matlab.ui.control.Label
    eBlock            matlab.ui.control.NumericEditField
    axes1             matlab.ui.control.UIAxes
    UIAxes            matlab.ui.control.UIAxes
  end

    
    properties (Access = private)
        LastPath = '' % Last file opened
        Data          % Loaded data
        nSlices = 0   % Number of slices
        Shots = []
        Signal = 0
        idxSignal = 1
        Ref = []
        BL  = []
    end
    
    methods (Access = private)
        function DrawIt(app)
            
            idx = app.slider1.Value;
            if idx < app.nSlices
                yyy = app.Data.y(:, idx) - app.BL;
                yyyy = app.Data.y(:, idx) - app.Ref;
            else
                yyy = app.Data.y(:, idx);
            end
            
            cla(app.axes1)
            plot(app.axes1, app.Data.ax.x, real(yyy)); hold(app.axes1, 'on');
            plot(app.axes1, app.Data.ax.x, imag(yyy));
            grid(app.axes1, 'on')
            
            if idx < app.nSlices
                plot(app.axes1, app.Data.ax.x, abs(yyy));
                plot(app.axes1, app.Data.ax.x, real(yyyy));
                legend(app.axes1, {'r','i','a','noise'});
                
                noise  = std(real(yyyy(app.idxSignal:end))) / sqrt(2);
                app.text2.Text = sprintf('%i: s=%7.5f n=%7.5f snr=%5.3f', app.Shots(idx), app.Signal, noise, app.Signal/noise);
            else
                app.text2.Text = 'baseline';
                legend(app.axes1, {'r','i'});
            end
        end
    end
    

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function SNRcheckGUI_OpeningFcn(app, varargin)
            
    end

    % Button pushed function: pushbutton1
    function pushbutton1_Callback(app, event)
            [fname, pathname] = uigetfile({'*.exp;*.tdms'}, 'Select a file', app.LastPath);
            if ~isequal(fname,0) && ~isequal(pathname,0)
                set(app.figure1, 'Name', fname)
                filename = fullfile(pathname, fname);
                
                %   [handles.ax, handles.y, handles.dsc] = kv_d01read(filename);
                
                [app.Data.ax, app.Data.y, app.Data.dsc] = kv_smtdmsread(filename);
                block_idx = app.eBlock.Value;
                block_idx = min(max(1, block_idx), size(app.Data.ax.x,1));
                app.Data.y(1:block_idx, :) = 0;
            
                app.nSlices = size(app.Data.y, 2);
                app.LastPath = pathname;
                
                n = max(app.nSlices, 1);
                app.slider1.Limits = [1 n];
                app.slider1.MajorTicks = 1 : n;
                
                shots = app.Data.dsc.params_Shots;
                shots = shots(1:(strfind(shots, ';')-1));
                app.Shots = sscanf(shots, "%i,", inf);
                app.Shots = app.Shots(1:n);
                
                app.BL  = app.Data.y(:, end);
                app.Ref = app.Data.y(:, end-1);
                [app.Signal, app.idxSignal] = max(real(app.Ref - app.BL));
                
                noise = zeros(app.nSlices,1);
                for ii=1:app.nSlices
                    yy = app.Data.y(:, ii) - app.Ref; 
                    noise(ii)  = std(real(yy(app.idxSignal:end))) / sqrt(2);
                end
                SNR = app.Signal./noise./sqrt(app.Shots);
                cla(app.UIAxes)
                semilogx(app.UIAxes, app.Shots, SNR,'o:')
                axis(app.UIAxes, 'tight')
                grid(app.UIAxes, 'on')
                title(app.UIAxes, sprintf('SNR/Shot %4.3f', max(SNR(1:end-2))))
                DrawIt(app);
            end
    end

    % Value changed function: slider1
    function slider1_Callback(app, event)
            app.slider1.Value = round(app.slider1.Value); % get then round
            DrawIt(app);
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.figure1 = uifigure('Visible', 'off');
      app.figure1.Name = 'SNRcheckGUI';
      app.figure1.Tag = 'figure1';
      app.GridLayout = uigridlayout(app.figure1);
      app.pushbutton1 = uibutton(app.GridLayout, 'push');
      app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
      app.pushbutton1.Tag = 'pushbutton1';
      app.pushbutton1.Text = 'Load Data';
      app.text2 = uilabel(app.GridLayout);
      app.text2.Tag = 'text2';
      app.text2.Text = '>';
      app.slider1 = uislider(app.GridLayout);
      app.slider1.Limits = [0 1];
      app.slider1.MajorTicks = [];
      app.slider1.ValueChangedFcn = createCallbackFcn(app, @slider1_Callback, true);
      app.slider1.MinorTicks = [];
      app.slider1.Tag = 'slider1';
      app.GridLayout2 = uigridlayout(app.GridLayout);
      app.BlockpointsLabel = uilabel(app.GridLayout2);
      app.BlockpointsLabel.Text = 'Block points:';
      app.eBlock = uieditfield(app.GridLayout2, 'numeric');
      app.eBlock.Limits = [0 Inf];
      app.eBlock.ValueDisplayFormat = '%6i';
      app.axes1 = uiaxes(app.GridLayout);
      app.axes1.Tag = 'axes1';
      app.UIAxes = uiaxes(app.GridLayout);
      xlabel(app.UIAxes, 'N')
      ylabel(app.UIAxes, 'SNR')
      zlabel(app.UIAxes, 'Z')
      % Show the figure after all components are created
      app.figure1.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = SNRcheckAPP(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.figure1)

      % Execute the startup function
      runStartupFcn(app, @(app)SNRcheckGUI_OpeningFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.figure1)
    end
  end
end