classdef SlicerAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Menu                         matlab.ui.container.Menu
        mExportDICOM                 matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout4                  matlab.ui.container.GridLayout
        ClearPlotButton              matlab.ui.control.Button
        btnPlot3D                    matlab.ui.control.Button
        GridLayout3                  matlab.ui.container.GridLayout
        GridLayout7                  matlab.ui.container.GridLayout
        projectionsLines             matlab.ui.control.CheckBox
        projections                  matlab.ui.control.CheckBox
        GridLayout6                  matlab.ui.container.GridLayout
        txtColorMAX                  matlab.ui.control.NumericEditField
        ColorRangeMAXEditFieldLabel  matlab.ui.control.Label
        txtColorMIN                  matlab.ui.control.NumericEditField
        ColorRangeMINEditFieldLabel  matlab.ui.control.Label
        GridLayout5                  matlab.ui.container.GridLayout
        txtAlphaValue                matlab.ui.control.EditField
        AlphavalueforbodyEditFieldLabel  matlab.ui.control.Label
        ckboxUseMask                 matlab.ui.control.CheckBox
        txtSaggittalCut              matlab.ui.control.NumericEditField
        PlaneofsaggittalcutEditFieldLabel  matlab.ui.control.Label
        txtCoronalCut                matlab.ui.control.NumericEditField
        PlaneofcoronalcutEditFieldLabel  matlab.ui.control.Label
        txtROImax                    matlab.ui.control.NumericEditField
        RangeformaskaxialMAXEditFieldLabel  matlab.ui.control.Label
        txtROImin                    matlab.ui.control.NumericEditField
        RangeforMaskaxialMINInsert100Label  matlab.ui.control.Label
        GridLayout2                  matlab.ui.container.GridLayout
        separateWindow               matlab.ui.control.Button
        txtImageName                 matlab.ui.control.EditField
        InsertnameofimageofinterestofregistrationfileLabel  matlab.ui.control.Label
        txtMaskName                  matlab.ui.control.EditField
        InsertnameofmaskofinterestofregistrationfileLabel  matlab.ui.control.Label
        txtRegFile                   matlab.ui.control.EditField
        SelectaregistrationfileEditFieldLabel  matlab.ui.control.Label
        btnBrowseArbuz               matlab.ui.control.Button
        axMainPlot                   matlab.ui.control.UIAxes
    end

  
  properties (Access = public)
    pathToRegistration = "Z:\Data\Collaborators\PaulEric\09052024\arbuz.mat";
    pathToMask = "Z:\Data\Collaborators\PaulEric\09052024\KidneyM024.mat";
    ROImin = 0;
    ROImax = 0;
    coronalCut  =NaN;
    saggittalCut = NaN;
  end
  
  methods (Access = private)
    
    function fMainPlot(app, hh)
        %plot(1:5, sin(1:5), 'Parent', hh)
        threed_viewer(app, hh)
        
    end
  end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnBrowseArbuz
        function btnBrowseArbuzButtonPushed(app, event)
      % Add code to browse Arbuz
      [file,location] = uigetfile;
      app.pathToRegistration = [location, file];
      app.txtRegFile.Value = app.pathToRegistration;
        end

        % Button pushed function: btnPlot3D
        function btnPlot3DButtonPushed(app, event)
      %cla(app.axMainPlot, 'reset');
      app.fMainPlot(app.axMainPlot)
        end

        % Value changed function: txtROImin
        function fcnRangeROImin(app, event)
      if app.txtROImin == -100
        app.ROImin = NaN;
      else
        app.ROImin = app.txtROImin.Value;
      end
      if app.ROImax < app.ROImin
        app.ROImax = app.ROImin;
        app.txtROImax.Value = app.ROImax;
      end
        end

        % Value changed function: txtROImax
        function fcnRangeROImax(app, event)
      if app.txtROImax.Value == -100
        app.ROImax = NaN;
      else
        app.ROImax = app.txtROImax.Value;
      end
      if app.ROImax < app.ROImin
        app.ROImin = app.txtROImax.Value;
        app.txtROImin.Value = app.ROImin;
      end
        end

        % Button pushed function: ClearPlotButton
        function btnClearPlotPushed(app, event)
      cla(app.axMainPlot, 'reset');
        end

        % Value changed function: txtCoronalCut
        function fcnCoronalCut(app, event)
      app.coronalCut = app.txtCoronalCut.Value;
        end

        % Value changed function: txtSaggittalCut
        function fcnSaggittalCut(app, event)
      app.saggittalCut = app.txtSaggittalCut.Value;
      
        end

        % Value changed function: ckboxUseMask
        function fcnUseMask(app, event)
      value = app.ckboxUseMask.Value;
      cla(app.axMainPlot, 'reset');
      app.fMainPlot(app.axMainPlot)
        end

        % Value changed function: txtAlphaValue
        function fcnAlphaValueChanged(app, event)
      value = app.txtAlphaValue.Value;
      cla(app.axMainPlot, 'reset');
      app.fMainPlot(app.axMainPlot)
        end

        % Value changed function: txtMaskName
        function fcnMaskSelected(app, event)
      value = app.txtMaskName.Value;
      
        end

        % Value changed function: txtColorMAX, txtColorMIN
        function fcnRangeChanged(app, event)
      value = app.txtColorMAX.Value;
      clim(app.axMainPlot, [app.txtColorMIN.Value app.txtColorMAX.Value])
        end

        % Menu selected function: mExportDICOM
        function fcnDICOMexport(app, event)
      
        end

        % Value changed function: projections
        function fcnApplyProjections(app, event)
            value = app.projections.Value;
            cla(app.axMainPlot);
            app.fMainPlot(app.axMainPlot);
        end

        % Value changed function: projectionsLines
        function fcnApplyProjectionsLines(app, event)
            value = app.projectionsLines.Value;
            cla(app.axMainPlot);
            app.fMainPlot(app.axMainPlot);
        end

        % Button pushed function: separateWindow
        function fcnSeparateWindow(app, event)
            fig = figure(22);
            ax = axes('Parent', fig);
            app.fMainPlot(ax);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.mExportDICOM = uimenu(app.Menu);
            app.mExportDICOM.MenuSelectedFcn = createCallbackFcn(app, @fcnDICOMexport, true);
            app.mExportDICOM.Text = 'export DICOM';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.axMainPlot = uiaxes(app.GridLayout);
            title(app.axMainPlot, 'Title')
            xlabel(app.axMainPlot, 'X')
            ylabel(app.axMainPlot, 'Y')
            zlabel(app.axMainPlot, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.btnBrowseArbuz = uibutton(app.GridLayout2, 'push');
            app.btnBrowseArbuz.ButtonPushedFcn = createCallbackFcn(app, @btnBrowseArbuzButtonPushed, true);
            app.btnBrowseArbuz.Text = 'Browse';
            app.SelectaregistrationfileEditFieldLabel = uilabel(app.GridLayout2);
            app.SelectaregistrationfileEditFieldLabel.Text = 'Select a registration file';
            app.txtRegFile = uieditfield(app.GridLayout2, 'text');
            app.InsertnameofmaskofinterestofregistrationfileLabel = uilabel(app.GridLayout2);
            app.InsertnameofmaskofinterestofregistrationfileLabel.Text = 'Insert name of mask of interest of registration file:';
            app.txtMaskName = uieditfield(app.GridLayout2, 'text');
            app.txtMaskName.ValueChangedFcn = createCallbackFcn(app, @fcnMaskSelected, true);
            app.InsertnameofimageofinterestofregistrationfileLabel = uilabel(app.GridLayout2);
            app.InsertnameofimageofinterestofregistrationfileLabel.Text = 'Insert name of image of interest of registration file:';
            app.txtImageName = uieditfield(app.GridLayout2, 'text');
            app.separateWindow = uibutton(app.GridLayout2, 'push');
            app.separateWindow.ButtonPushedFcn = createCallbackFcn(app, @fcnSeparateWindow, true);
            app.separateWindow.Text = 'Plot in separate window';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.RangeforMaskaxialMINInsert100Label = uilabel(app.GridLayout3);
            app.RangeforMaskaxialMINInsert100Label.Text = {'Range for Mask (axial) - '; 'MIN'};
            app.txtROImin = uieditfield(app.GridLayout3, 'numeric');
            app.txtROImin.ValueChangedFcn = createCallbackFcn(app, @fcnRangeROImin, true);
            app.RangeformaskaxialMAXEditFieldLabel = uilabel(app.GridLayout3);
            app.RangeformaskaxialMAXEditFieldLabel.Text = 'Range for mask (axial) - MAX';
            app.txtROImax = uieditfield(app.GridLayout3, 'numeric');
            app.txtROImax.ValueChangedFcn = createCallbackFcn(app, @fcnRangeROImax, true);
            app.PlaneofcoronalcutEditFieldLabel = uilabel(app.GridLayout3);
            app.PlaneofcoronalcutEditFieldLabel.Text = 'Plane of coronal cut';
            app.txtCoronalCut = uieditfield(app.GridLayout3, 'numeric');
            app.txtCoronalCut.ValueChangedFcn = createCallbackFcn(app, @fcnCoronalCut, true);
            app.PlaneofsaggittalcutEditFieldLabel = uilabel(app.GridLayout3);
            app.PlaneofsaggittalcutEditFieldLabel.Text = 'Plane of saggittal cut';
            app.txtSaggittalCut = uieditfield(app.GridLayout3, 'numeric');
            app.txtSaggittalCut.ValueChangedFcn = createCallbackFcn(app, @fcnSaggittalCut, true);
            app.ckboxUseMask = uicheckbox(app.GridLayout3);
            app.ckboxUseMask.ValueChangedFcn = createCallbackFcn(app, @fcnUseMask, true);
            app.ckboxUseMask.Text = 'Use mask (if not, all image will be shown)';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.AlphavalueforbodyEditFieldLabel = uilabel(app.GridLayout5);
            app.AlphavalueforbodyEditFieldLabel.Text = 'Alpha value for  body';
            app.txtAlphaValue = uieditfield(app.GridLayout5, 'text');
            app.txtAlphaValue.ValueChangedFcn = createCallbackFcn(app, @fcnAlphaValueChanged, true);
            app.txtAlphaValue.Value = '0.1';
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.ColorRangeMINEditFieldLabel = uilabel(app.GridLayout6);
            app.ColorRangeMINEditFieldLabel.Text = 'Color Range MIN';
            app.txtColorMIN = uieditfield(app.GridLayout6, 'numeric');
            app.txtColorMIN.ValueChangedFcn = createCallbackFcn(app, @fcnRangeChanged, true);
            app.ColorRangeMAXEditFieldLabel = uilabel(app.GridLayout6);
            app.ColorRangeMAXEditFieldLabel.Text = 'Color Range MAX';
            app.txtColorMAX = uieditfield(app.GridLayout6, 'numeric');
            app.txtColorMAX.ValueChangedFcn = createCallbackFcn(app, @fcnRangeChanged, true);
            app.txtColorMAX.Value = 150;
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.projections = uicheckbox(app.GridLayout7);
            app.projections.ValueChangedFcn = createCallbackFcn(app, @fcnApplyProjections, true);
            app.projections.Text = 'Apply Projections';
            app.projectionsLines = uicheckbox(app.GridLayout7);
            app.projectionsLines.ValueChangedFcn = createCallbackFcn(app, @fcnApplyProjectionsLines, true);
            app.projectionsLines.Text = 'Apply Projections lines';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.btnPlot3D = uibutton(app.GridLayout4, 'push');
            app.btnPlot3D.ButtonPushedFcn = createCallbackFcn(app, @btnPlot3DButtonPushed, true);
            app.btnPlot3D.Text = 'Plot';
            app.ClearPlotButton = uibutton(app.GridLayout4, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @btnClearPlotPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SlicerAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end