classdef pvGUI2 < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    figure1              matlab.ui.Figure
    mEdit                matlab.ui.container.Menu
    mEditProjectList     matlab.ui.container.Menu
    mEditExerimentList   matlab.ui.container.Menu
    mEditCommentScript   matlab.ui.container.Menu
    mEditDrawScript      matlab.ui.container.Menu
    mEditAnalysis        matlab.ui.container.Menu
    ToolsMenu            matlab.ui.container.Menu
    ClearProcessingMenu  matlab.ui.container.Menu
    GridLayout           matlab.ui.container.GridLayout
    uipanel1             matlab.ui.container.Panel
    eLogWindow           matlab.ui.control.TextArea
    pbReportView         matlab.ui.control.Button
    pmReportView         matlab.ui.control.DropDown
    pmShowGroup          matlab.ui.control.DropDown
    eViewerFigure        matlab.ui.control.EditField
    pb3D                 matlab.ui.control.Button
    pbLoadOriginal       matlab.ui.control.Button
    pbViewer             matlab.ui.control.Button
    lbImages             matlab.ui.control.ListBox
    cbLoadRegistration   matlab.ui.control.Button
    uipanel2             matlab.ui.container.Panel
    cbReprocess          matlab.ui.control.CheckBox
    pbOpenFolder         matlab.ui.control.Button
    lbRegistrations      matlab.ui.control.ListBox
    pbDBGUITEST          matlab.ui.control.Button
    pbDBGUI              matlab.ui.control.Button
    pbProcessSingle      matlab.ui.control.Button
    pbProcessAllData     matlab.ui.control.Button
    pbArbuzGUI           matlab.ui.control.Button
    CommentsPanel        matlab.ui.container.Panel
    eComments            matlab.ui.control.TextArea
    ExperimentPanel      matlab.ui.container.Panel
    lbExp                matlab.ui.control.ListBox
    ProjectPanel         matlab.ui.container.Panel
    pmProject            matlab.ui.control.DropDown
  end

  
  properties (Access = private)
    the_project % Description
    projects % Description
    root % Description
    hGUI % Description
    the_experiment % Description
    ActiveReports % Description
    p_path % Description
    experiments % Description
    db % Description
    DDstyleProjectCaption % Description
  end

  methods (Access = private)
    function backup_file(app, file_name)
      % --------------------------------------------------------------------

      pvGUI_filename = which(mfilename);
      [pvGUI_folder] = fileparts(pvGUI_filename);

      backup_folder = fullfile(pvGUI_folder, 'backup');
      if ~exist(backup_folder, 'dir')
        mkdir(backup_folder);
      end

      if contains(file_name, pvGUI_folder)
        pos = strfind(file_name, pvGUI_folder);
        backup_file = file_name(pos+length(pvGUI_folder):end);
        backup_file(backup_file == '\') = '_';
        backup_file = fullfile(backup_folder, [char(datetime('now','Format', 'HHmmSS-yyMMdd')),backup_file]);
        copyfile(file_name, backup_file);
      end
    end

    function out = change_drive(app, in, new_drive)
      out=in;

      if ~isempty(new_drive) && length(new_drive) == 1
        for ii=1:length(out)
          pos = strfind(out{ii}, ':\');
          if ~isempty(pos) && pos == 2
            ppp = out{ii}; ppp(1) = new_drive; out{ii} = ppp;
          end
        end
      end
    end

    % --------------------------------------------------------------------
    function old_path = my_cd(app, new_path)
      old_path = pwd;
      cd(new_path);
    end

    % --------------------------------------------------------------------
    function [str, style] = pv_draw_item_ext(app, val)
      try
        [str, style] = pv_draw_item(val);
      catch err
        % support for the old style pv_draw_item
        str = pv_draw_item(val);
        style = uistyle(FontColor="black", FontWeight="normal");
      end
      str = remove_tags(app, str);
    end

    % --------------------------------------------------------------------
    function untagged = remove_tags(app, tagged)
      untagged = regexprep( tagged, '<.*?>', '' ) ;
      untagged = regexprep( untagged, '&nbsp;', ' ') ;      
    end

  end


  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function pvGUI_OpeningFcn(app, varargin)
      % --------------------------------------------------------------------

      % Ensure that the app appears on screen when run
      movegui(app.figure1, 'onscreen');

      app.DDstyleProjectCaption = uistyle(BackgroundColor=[0.8,0.8,1], FontWeight="bold");

      app.hGUI = app.figure1;
      app.the_project = -1;
      app.the_experiment = -1;

      % load project list
      project_list;
      app.projects = EPRproject;
      str = {};
      for ii=1:length(app.projects)
        str{end+1} = app.projects{ii}.title;
      end
      app.pmProject.Items = str;
      app.pmProject.ItemsData = 1:length(str);
      for ii=1:length(app.pmProject.Items)
        if contains(app.projects{ii}.title, '----')
          addStyle(app.pmProject,app.DDstyleProjectCaption,"item",ii);
        end
      end

      % find the root
      app.root = fileparts(mfilename('fullpath'));

      app.ActiveReports = [];

      pmProject_Callback(app);
    end

    % Button pushed function: cbLoadRegistration
    function cbLoadRegistration_Callback(app, event)
      % --------------------------------------------------------------------

      if ~isfield(app.experiments{app.the_experiment}, 'registration') || ...
          isempty(app.experiments{app.the_experiment}.registration)
        arbuz_ShowMessage(app.hGUI, sprintf('Experiment %s has no registration.', app.experiments{app.the_experiment}.tag));
        app.lbImages.Items = {'No data'};
        cd(current_directory);
        return;
      end

      arbuz_OpenProject(app.figure1, app.experiments{app.the_experiment}.registration);

      res = arbuz_FindImage(app.figure1, 'master', '', '', {'Name'});
      str = {};
      for ii=1:length(res)
        str{end+1} = res{ii}.Name;
      end
      app.lbImages.Items = str;
      tag = safeget(app.experiments{app.the_experiment}, 'tag', '');
      app.uipanel1.Title = ['Registration browser - ',tag];

      saves = arbuz_GetSaveParList(app.figure1, '');
      if isempty(saves)
        app.pmReportView.Items = {'No scenes found.'};
      else
        app.pmReportView.Items = saves;
      end
    end

    % Value changed function: lbExp
    function lbExp_Callback(app, event)
      % --------------------------------------------------------------------

      app.the_experiment = app.lbExp.Value;

      current_directory = pwd;
      cd(app.p_path);

      % Load project specific comments
      p = [];
      try
        p = app.db{app.the_experiment};
      catch err
      end
      app.experiments{app.the_experiment}.db = p;
      res = pv_item_comment(app.experiments{app.the_experiment});
      app.eComments.Value = res;

      % verify registration
      registration = safeget(app.experiments{app.the_experiment}, 'registration', '');
      if iscell(registration)
      else
        registration = {registration};
      end
      app.lbRegistrations.Items = registration;
      app.lbRegistrations.ItemsData = 1:length(registration);
      app.lbRegistrations.Value = 1;

      rrg = registration{1};
      if exist(rrg, 'file') == 2 || exist([rrg,'.mat'], 'file') == 2
        app.pbArbuzGUI.FontWeight = 'bold';
        app.pbArbuzGUI.Enable = 'on';
      else
        app.pbArbuzGUI.FontWeight = 'normal';
        app.pbArbuzGUI.Enable = 'off';
      end

      cd(current_directory);
    end

    % Menu selected function: mEditAnalysis, mEditCommentScript, 
    % ...and 3 other components
    function mEdit_Callback(app, event)
      % --------------------------------------------------------------------

      % external editor
      editor = System.Diagnostics.Process();
      editor.StartInfo.FileName = 'notepad.exe';
      if exist('c:\Program Files (x86)\Notepad++\notepad++.exe', 'file'), editor.StartInfo.FileName = '"c:\Program Files (x86)\Notepad++\notepad++.exe"'; end

      switch event.Source
        case app.mEditAnalysis
          the_sel = get(app.pmProject, 'value');
          the_path = fullfile(app.root, app.projects{the_sel}.folder);
          if exist(the_path, 'file') ~= 7, return; end
          argument = fullfile(the_path,'pv_analysis.m');
          editor.StartInfo.Arguments = argument;
          %     Start(editor);
          backup_file(app, argument)
          eval(['edit ' , argument]);
        case app.mEditDrawScript
          the_sel = get(app.pmProject, 'value');
          the_path = fullfile(app.root, app.projects{the_sel}.folder);
          if exist(the_path, 'file') ~= 7, return; end
          argument = fullfile(the_path,'pv_draw_item.m');
          editor.StartInfo.Arguments = argument;
          %     Start(editor);
          backup_file(app, argument)
          eval(['edit ' , argument]);
        case app.mEditCommentScript
          the_sel = get(app.pmProject, 'value');
          the_path = fullfile(app.root, app.projects{the_sel}.folder);
          if exist(the_path, 'file') ~= 7, return; end
          argument = fullfile(the_path,'pv_item_comment.m');
          editor.StartInfo.Arguments = argument;
          %     Start(editor);
          backup_file(app, argument)
          eval(['edit ' , argument]);
        case app.mEditExerimentList
          the_sel = get(app.pmProject, 'value');
          the_path = fullfile(app.root, app.projects{the_sel}.folder);
          if exist(the_path, 'file') ~= 7
            error('File does not exist.');
          else
            argument = fullfile(the_path,'pv_database.m');
            editor.StartInfo.Arguments = argument;
            %     Start(editor);
            backup_file(app, argument)
            eval(['edit(''' , argument, ''')']);
          end
        case app.mEditProjectList
          argument = fullfile(app.root,'project_list.m');
          editor.StartInfo.Arguments = argument;
          %     Start(editor);
          backup_file(app, argument)
          eval(['edit ' , argument]);
      end
    end

    % Button pushed function: pb3D
    function pb3D_Callback(app, event)
      % --------------------------------------------------------------------

      arbuz_RedrawAll(app.figure1)
    end

    % Button pushed function: pbArbuzGUI
    function pbArbuzGUI_Callback(app, event)
      % --------------------------------------------------------------------

      if app.the_experiment < 0, return; end
      registrations = app.experiments{app.the_experiment}.registration;
      if iscell(registrations)
        registration = registrations{app.lbRegistrations.Value};
      else
        registration = registrations;
      end
      ArbuzGUI([], registration);
    end

    % Button pushed function: pbDBGUI
    function pbDBGUI_Callback(app, event)
      % --------------------------------------------------------------------

      % pvDBGUI(handles);
    end

    % Button pushed function: pbLoadOriginal
    function pbLoadOriginal_Callback(app, event)
      % --------------------------------------------------------------------

      res = arbuz_FindImage(app.figure1, 'master', 'Name', app.lbImages.Value, {'Name', 'FileName'});
      ibGUI(res{1}.FileName)
    end

    % Button pushed function: pbOpenFolder
    function pbOpenFolder_Callback(app, event)
      % --------------------------------------------------------------------

      if app.the_experiment < 0, return; end
      registrations = app.experiments{app.the_experiment}.registration;
      if iscell(registrations)
        registration = registrations{app.lbRegistrations.Value};
      else
        registration = registrations;
      end
      winopen(fileparts(registration));
    end

    % Button pushed function: pbProcessAllData, pbProcessSingle
    function pbProcessAllData_Callback(app, event)
      % --------------------------------------------------------------------

      app.p_path = fullfile(app.root, app.projects{app.the_project}.folder);
      cd(app.p_path);

      try
        all_processing = pv_analysis;
      catch
        disp('Processing is not defined for this project.');
        return;
      end

      str = {};
      for ii=1:length(all_processing), str{end+1} = all_processing{ii}.title; end

      isReprocess = app.cbReprocess.Value;

      [sel,isOK] = listdlg('PromptString','Select processing:',...
        'SelectionMode','single','ListSize', [180,250], ...
        'ListString',str);

      if isOK
        parameters = all_processing{sel}.parameters;
        parameters.path = app.p_path;
        parameters.reprocess = isReprocess;
        parameters.project  = app.the_project;
        parameters.experiment = app.the_experiment;

        switch(event.Source)
          case app.pbProcessSingle
            parameters.use_single = 1;
          otherwise
            parameters.use_single = 0;
        end

        eval([all_processing{sel}.function,'(app.experiments,parameters);']);
        app.db = pv_processing_loader(app.p_path);
      end
    end

    % Button pushed function: pbReportView
    function pbReportView_Callback(app, event)
      % --------------------------------------------------------------------

      the_save = arbuz_GetSavePar(app.figure1, app.pmReportView.Value);
      if ~isempty(the_save)
        eval([the_save.renderer, '(app.figure1, the_save)'])
      end
    end

    % Button pushed function: pbViewer
    function pbViewer_Callback(app, event)
      % --------------------------------------------------------------------

      res = arbuz_FindImage(app.figure1, 'master', 'Name', app.lbImages.Value, {'Name'});
      options.image.ImageIdx = res{1}.ImageIdx;
      options.image.SlaveIdx = -1;
      arbuz_DefaultRDR(app.hGUI, options);
    end

    % Value changed function: pmProject
    function pmProject_Callback(app, event)
      % --------------------------------------------------------------------

      app.the_project = app.pmProject.Value;
      app.p_path = fullfile(app.root, app.projects{app.the_project}.folder);
      if strfind(app.projects{app.the_project}.folder, '------'), return; end
      app.the_experiment = -1;

      current_directory = pwd;
      if exist(app.p_path, 'file') ~= 7
        fprintf('Directory %s not found.\n', app.p_path);

        ButtonName = questdlg(sprintf('Directory %s not found. Create new project ?', app.p_path), ...
          'New project ?', ...
          'Yes', 'No', 'Cancel');
        switch ButtonName
          case 'Yes'
            % create directory
            cd(app.root); mkdir(app.projects{app.the_project}.folder); cd(current_directory);
          case 'No', return;
          case 'Cancel', return;
        end
      end

      cd(app.p_path);

      if exist(fullfile(app.p_path, 'pv_database.m'), 'file') ~= 2
        ButtonName = questdlg('Database file is not found. Create new database ?', ...
          'New project ?', ...
          'Yes', 'No', 'Cancel');
        switch ButtonName
          case 'Yes'
            % create files
            fname = fullfile(app.p_path, 'pv_database.m');
            fid = fopen(fname, 'a+');
            if fid ~= -1, fprintf(fid, 'function expN = pv_database()\n\nexpN={};\n'); fclose(fid); end
          case 'No', return;
          case 'Cancel', return;
        end
      end
      app.experiments = pv_database;

      if exist(fullfile(app.p_path, 'pv_draw_item.m'), 'file') ~= 2
        % create files
        fname = fullfile(app.p_path, 'pv_draw_item.m');
        fid = fopen(fname, 'a+');
        if fid ~= -1, fprintf(fid, 'function item_text = pv_draw_item(the_item)\n\nitem_text = the_item.tag;\n'); fclose(fid); end
      end

      if exist(fullfile(app.p_path, 'pv_item_comment.m'), 'file') ~= 2
        % create files
        fname = fullfile(app.p_path, 'pv_item_comment.m');
        fid = fopen(fname, 'a+');
        if fid ~= -1, fprintf(fid, 'function res = pv_item_comment(the_item)\n\nres = [''registration:'', the_item.registration];\n'); fclose(fid); end
      end

      if exist(fullfile(app.p_path, 'pv_analysis.m'), 'file') ~= 2
        % create files
        fname = fullfile(app.p_path, 'pv_analysis.m');
        fid = fopen(fname, 'a+');
        if fid ~= -1, fprintf(fid, 'function res = pv_analysis\n\n'); fclose(fid); end
      end

      str = {}; style = {};
      for ii=1:length(app.experiments)
        [str{ii}, style{ii}] = pv_draw_item_ext(app, app.experiments{ii});
      end
      app.lbExp.Items = str;
      app.lbExp.ItemsData = 1:length(str);
      app.lbExp.Value = 1;
      for ii=1:length(app.experiments)
        addStyle(app.lbExp,style{ii},"item",ii);
      end

      cd(current_directory);

      app.db = pv_processing_loader(app.p_path);

    end

    % Size changed function: figure1
    function figure1SizeChanged(app, event)
      % position = app.figure1.Position;
      
    end

    % Size changed function: ExperimentPanel
    function ExperimentPanelSizeChanged(app, event)
      position = app.ExperimentPanel.Position;
      app.lbExp.Position = [1,1,position(3)-2, position(4)-22];
    end

    % Size changed function: CommentsPanel
    function CommentsPanelSizeChanged(app, event)
      position = app.CommentsPanel.Position;
      app.eComments.Position = [1,1,position(3)-2, position(4)-22];      
    end

    % Size changed function: ProjectPanel
    function ProjectPanelSizeChanged(app, event)
      position = app.ProjectPanel.Position;
      app.pmProject.Position = [1,1,position(3)-2, position(4)-22];  
    end

    % Menu selected function: ClearProcessingMenu
    function ClearProcessingMenuSelected(app, event)
      fname = fullfile(app.p_path, 'db.mat');
      db = [];
      save(fname, 'db');

      app.db = pv_processing_loader(app.p_path);
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      % Get the file path for locating images
      pathToMLAPP = fileparts(mfilename('fullpath'));
      app.figure1 = uifigure('Visible', 'off');
      app.figure1.Name = 'Project Viewer: Images to the People';
      app.figure1.SizeChangedFcn = createCallbackFcn(app, @figure1SizeChanged, true);
      app.figure1.Tag = 'figure1';
      app.mEdit = uimenu(app.figure1);
      app.mEdit.Text = 'Edit';
      app.mEdit.Tag = 'mEdit';
      app.mEditProjectList = uimenu(app.mEdit);
      app.mEditProjectList.MenuSelectedFcn = createCallbackFcn(app, @mEdit_Callback, true);
      app.mEditProjectList.Text = 'Edit project list';
      app.mEditProjectList.Tag = 'mEditProjectList';
      app.mEditExerimentList = uimenu(app.mEdit);
      app.mEditExerimentList.MenuSelectedFcn = createCallbackFcn(app, @mEdit_Callback, true);
      app.mEditExerimentList.Separator = 'on';
      app.mEditExerimentList.Text = 'Edit experiment list';
      app.mEditExerimentList.Tag = 'mEditExerimentList';
      app.mEditCommentScript = uimenu(app.mEdit);
      app.mEditCommentScript.MenuSelectedFcn = createCallbackFcn(app, @mEdit_Callback, true);
      app.mEditCommentScript.Text = 'Edit comment script';
      app.mEditCommentScript.Tag = 'mEditCommentScript';
      app.mEditDrawScript = uimenu(app.mEdit);
      app.mEditDrawScript.MenuSelectedFcn = createCallbackFcn(app, @mEdit_Callback, true);
      app.mEditDrawScript.Text = 'Edit drawing script';
      app.mEditDrawScript.Tag = 'mEditDrawScript';
      app.mEditAnalysis = uimenu(app.mEdit);
      app.mEditAnalysis.MenuSelectedFcn = createCallbackFcn(app, @mEdit_Callback, true);
      app.mEditAnalysis.Text = 'Edit analysis script';
      app.mEditAnalysis.Tag = 'mEditAnalysis';
      app.ToolsMenu = uimenu(app.figure1);
      app.ToolsMenu.Text = 'Tools';
      app.ClearProcessingMenu = uimenu(app.ToolsMenu);
      app.ClearProcessingMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearProcessingMenuSelected, true);
      app.ClearProcessingMenu.Text = 'Clear Processing';
      app.GridLayout = uigridlayout(app.figure1);
      app.ProjectPanel = uipanel(app.GridLayout);
      app.ProjectPanel.Title = 'Project';
      app.ProjectPanel.SizeChangedFcn = createCallbackFcn(app, @ProjectPanelSizeChanged, true);
      app.pmProject = uidropdown(app.ProjectPanel);
      app.pmProject.Items = {'O2 targeted IMRT', 'CASEY', 'Breast imaging', 'MKS_Breast_analysis', '25 mm bed evaluation', 'Heart_imaging'};
      app.pmProject.ValueChangedFcn = createCallbackFcn(app, @pmProject_Callback, true);
      app.pmProject.Tag = 'pmProject';
      app.pmProject.Value = 'O2 targeted IMRT';
      app.ExperimentPanel = uipanel(app.GridLayout);
      app.ExperimentPanel.Title = 'Experiment';
      app.ExperimentPanel.SizeChangedFcn = createCallbackFcn(app, @ExperimentPanelSizeChanged, true);
      app.lbExp = uilistbox(app.ExperimentPanel);
      app.lbExp.Items = {};
      app.lbExp.ValueChangedFcn = createCallbackFcn(app, @lbExp_Callback, true);
      app.lbExp.Tag = 'lbExp';
      app.lbExp.Value = {};
      app.CommentsPanel = uipanel(app.GridLayout);
      app.CommentsPanel.Title = 'Comments';
      app.CommentsPanel.SizeChangedFcn = createCallbackFcn(app, @CommentsPanelSizeChanged, true);
      app.eComments = uitextarea(app.CommentsPanel);
      app.eComments.Tag = 'eComments';
      app.eComments.Value = {'Comments'};
      app.uipanel2 = uipanel(app.GridLayout);
      app.uipanel2.Title = 'Action buttons';
      app.uipanel2.Tag = 'uipanel2';
      app.pbArbuzGUI = uibutton(app.uipanel2, 'push');
      app.pbArbuzGUI.ButtonPushedFcn = createCallbackFcn(app, @pbArbuzGUI_Callback, true);
      app.pbArbuzGUI.Tag = 'pbArbuzGUI';
      app.pbArbuzGUI.Text = 'ArbuzGUI';
      app.pbProcessAllData = uibutton(app.uipanel2, 'push');
      app.pbProcessAllData.ButtonPushedFcn = createCallbackFcn(app, @pbProcessAllData_Callback, true);
      app.pbProcessAllData.Tag = 'pbProcessAllData';
      app.pbProcessAllData.Text = 'Data analysis';
      app.pbProcessSingle = uibutton(app.uipanel2, 'push');
      app.pbProcessSingle.ButtonPushedFcn = createCallbackFcn(app, @pbProcessAllData_Callback, true);
      app.pbProcessSingle.Tag = 'pbProcessSingle';
      app.pbProcessSingle.Text = 'One Experiment';
      app.pbDBGUI = uibutton(app.uipanel2, 'push');
      app.pbDBGUI.ButtonPushedFcn = createCallbackFcn(app, @pbDBGUI_Callback, true);
      app.pbDBGUI.Tag = 'pbDBGUI';
      app.pbDBGUI.Text = 'DB editor';
      app.pbDBGUITEST = uibutton(app.uipanel2, 'push');
      app.pbDBGUITEST.Tag = 'pbDBGUITEST';
      app.pbDBGUITEST.Text = 'DB editor test';
      app.lbRegistrations = uilistbox(app.uipanel2);
      app.lbRegistrations.Items = {};
      app.lbRegistrations.Tag = 'lbRegistrations';
      app.lbRegistrations.Value = {};
      app.pbOpenFolder = uibutton(app.uipanel2, 'push');
      app.pbOpenFolder.ButtonPushedFcn = createCallbackFcn(app, @pbOpenFolder_Callback, true);
      app.pbOpenFolder.Tag = 'pbOpenFolder';
      app.pbOpenFolder.Icon = fullfile(pathToMLAPP, 'pbOpenFolder_image.png');
      app.pbOpenFolder.Text = '';
      app.cbReprocess = uicheckbox(app.uipanel2);
      app.cbReprocess.Tag = 'cbReprocess';
      app.cbReprocess.Tooltip = 'Reprocess data';
      app.cbReprocess.Text = 'RP';
      app.uipanel1 = uipanel(app.GridLayout);
      app.uipanel1.Title = 'Registration browser';
      app.uipanel1.Tag = 'uipanel1';
      app.cbLoadRegistration = uibutton(app.uipanel1, 'push');
      app.cbLoadRegistration.ButtonPushedFcn = createCallbackFcn(app, @cbLoadRegistration_Callback, true);
      app.cbLoadRegistration.Tag = 'cbLoadRegistration';
      app.cbLoadRegistration.Text = 'Load';
      app.lbImages = uilistbox(app.uipanel1);
      app.lbImages.Items = {'Select Exp'};
      app.lbImages.Tag = 'lbImages';
      app.lbImages.Value = 'Select Exp';
      app.pbViewer = uibutton(app.uipanel1, 'push');
      app.pbViewer.ButtonPushedFcn = createCallbackFcn(app, @pbViewer_Callback, true);
      app.pbViewer.Tag = 'pbViewer';
      app.pbViewer.Text = 'Viewer';
      app.pbLoadOriginal = uibutton(app.uipanel1, 'push');
      app.pbLoadOriginal.ButtonPushedFcn = createCallbackFcn(app, @pbLoadOriginal_Callback, true);
      app.pbLoadOriginal.Tag = 'pbLoadOriginal';
      app.pbLoadOriginal.Text = 'Locate Original';
      app.pb3D = uibutton(app.uipanel1, 'push');
      app.pb3D.ButtonPushedFcn = createCallbackFcn(app, @pb3D_Callback, true);
      app.pb3D.Tag = 'pb3D';
      app.pb3D.Text = 'Registration view';
      app.eViewerFigure = uieditfield(app.uipanel1, 'text');
      app.eViewerFigure.Tag = 'eViewerFigure';
      app.eViewerFigure.Value = '10';
      app.pmShowGroup = uidropdown(app.uipanel1);
      app.pmShowGroup.Items = {'Default'};
      app.pmShowGroup.Tag = 'pmShowGroup';
      app.pmShowGroup.Value = 'Default';
      app.pmReportView = uidropdown(app.uipanel1);
      app.pmReportView.Items = {'', '2D raster', '3D'};
      app.pmReportView.Tag = 'pmReportView';
      app.pmReportView.Value = '3D';
      app.pbReportView = uibutton(app.uipanel1, 'push');
      app.pbReportView.ButtonPushedFcn = createCallbackFcn(app, @pbReportView_Callback, true);
      app.pbReportView.Tag = 'pbReportView';
      app.pbReportView.Text = 'Render report';
      app.eLogWindow = uitextarea(app.uipanel1);
      app.eLogWindow.Tag = 'eLogWindow';
      % Show the figure after all components are created
      app.figure1.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = pvGUI2(varargin)

      runningApp = getRunningApp(app);

      % Check for running singleton app
      if isempty(runningApp)

        % Create UIFigure and components
        createComponents(app)

        % Register the app with App Designer
        registerApp(app, app.figure1)

        % Execute the startup function
        runStartupFcn(app, @(app)pvGUI_OpeningFcn(app, varargin{:}))
      else

        % Focus the running singleton app
        figure(runningApp.figure1)

        app = runningApp;
      end

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.figure1)
    end
  end
end